classdef Mohr_Circle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Viewin3DButton            matlab.ui.control.Button
        StressinplanePanel        matlab.ui.container.Panel
        tau_nMPaEditField         matlab.ui.control.NumericEditField
        tau_nMPaEditFieldLabel    matlab.ui.control.Label
        sigma_nMPaEditField       matlab.ui.control.NumericEditField
        sigma_nMPaEditFieldLabel  matlab.ui.control.Label
        NormalvectorPanel         matlab.ui.container.Panel
        n_zEditField              matlab.ui.control.NumericEditField
        n_zEditFieldLabel         matlab.ui.control.Label
        n_yEditField              matlab.ui.control.NumericEditField
        n_yEditFieldLabel         matlab.ui.control.Label
        n_xEditField              matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        PrincipalStressesPanel    matlab.ui.container.Panel
        sigma_zMPaEditField       matlab.ui.control.NumericEditField
        sigma_zMPaEditFieldLabel  matlab.ui.control.Label
        sigma_yMPaEditField       matlab.ui.control.NumericEditField
        sigma_yMPaEditFieldLabel  matlab.ui.control.Label
        sigma_xMPaEditField       matlab.ui.control.NumericEditField
        textittheta_3Label        matlab.ui.control.Label
        DMohrsCircleLabel         matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        AddplotButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddplotButton
        function AddplotButtonPushed(app, event)
            try
                % 입력값
                n_x = app.n_xEditField.Value;
                n_y = app.n_yEditField.Value;
                n_z = app.n_zEditField.Value;
                sigma_x = app.sigma_xMPaEditField.Value;
                sigma_y = app.sigma_yMPaEditField.Value;
                sigma_z = app.sigma_zMPaEditField.Value;

                % sigma_x, sigma_y, sigma_z를 크기순으로 정렬하여 sigma_1, sigma_2, sigma_3에 저장
                sigmas = sort([sigma_x, sigma_y, sigma_z], 'descend');
                sigma_1 = sigmas(1);  % 가장 큰 값
                sigma_2 = sigmas(2);  % 두 번째로 큰 값
                sigma_3 = sigmas(3);  % 가장 작은 값

                % 벡터 크기 계산
                size_n = sqrt(n_x^2 + n_y^2 + n_z^2);

                % sigma_x, sigma_y, sigma_z 중 가장 큰 값에 대응하는 n 성분을 n_max에 저장
                if sigma_x >= sigma_y && sigma_x >= sigma_z
                    n_max = n_x;
                elseif sigma_y >= sigma_x && sigma_y >= sigma_z
                    n_max = n_y;
                else
                    n_max = n_z;
                end

                % sigma_x, sigma_y, sigma_z 중 가장 작은 값에 대응하는 n 성분을 n_min에 저장
                if sigma_x <= sigma_y && sigma_x <= sigma_z
                    n_min = n_x;
                elseif sigma_y <= sigma_x && sigma_y <= sigma_z
                    n_min = n_y;
                else
                    n_min = n_z;
                end


                % 각도 phi와 theta 계산
                phi = acos(n_max / size_n);  % arccos -> acos (MATLAB 함수)
                theta = acos(n_min / size_n);

                % 원을 그리기 위한 각도 (0에서 pi까지, 반원만)
                theta_circle = linspace(0, pi, 100);

                % sigma_1과 sigma_2를 양 끝점으로 하는 원
                center_xy = (sigma_1 + sigma_2) / 2;
                radius_xy = abs(sigma_2 - sigma_1) / 2;
                x_circle_xy = center_xy + radius_xy * cos(theta_circle);
                y_circle_xy = radius_xy * sin(theta_circle);

                % sigma_2와 sigma_3를 양 끝점으로 하는 원
                center_yz = (sigma_2 + sigma_3) / 2;
                radius_yz = abs(sigma_3 - sigma_2) / 2;
                x_circle_yz = center_yz + radius_yz * cos(theta_circle);
                y_circle_yz = radius_yz * sin(theta_circle);

                % sigma_1과 sigma_3를 양 끝점으로 하는 원
                center_xz = (sigma_1 + sigma_3) / 2;
                radius_xz = abs(sigma_3 - sigma_1) / 2;
                x_circle_xz = center_xz + radius_xz * cos(theta_circle);
                y_circle_xz = radius_xz * sin(theta_circle);

                % 동경에 해당하는 점 계산 (반시계 방향으로 phi만큼 회전)
                x_phi = center_xy + radius_xy * cos(2 * phi);
                y_phi = radius_xy * sin(2 * phi);

                % 동경에 해당하는 점 계산 (시계 방향으로 theta만큼 회전)
                x_theta = center_yz - radius_yz * cos(2 * theta);  % 시계 방향이므로 -theta
                y_theta = radius_yz * sin(2 * theta);

                % 점선으로 된 원 추가 계산
                % sigma_1과 sigma_2를 지름으로 하는 원의 중심을 중심으로 하고 (x_theta, y_theta)를 지나는 원
                radius_theta_circle = sqrt((x_theta - center_xy)^2 + (y_theta - 0)^2);
                x_dashed_circle_theta = center_xy + radius_theta_circle * cos(theta_circle);
                y_dashed_circle_theta = radius_theta_circle * sin(theta_circle);

                % sigma_2와 sigma_3를 지름으로 하는 원의 중심을 중심으로 하고 (x_phi, y_phi)를 지나는 원
                radius_phi_circle = sqrt((x_phi - center_yz)^2 + (y_phi - 0)^2);
                x_dashed_circle_phi = center_yz + radius_phi_circle * cos(theta_circle);
                y_dashed_circle_phi = radius_phi_circle * sin(theta_circle);

                % 두 원의 교점 계산: 수치적으로 계산
                % 원1: (x - center_xy)^2 + y^2 = radius_theta_circle^2
                % 원2: (x - center_yz)^2 + y^2 = radius_phi_circle^2

                % 거리와 중간 값 계산
                d = abs(center_xy - center_yz);  % 두 원의 중심 사이의 거리
                if d > (radius_theta_circle + radius_phi_circle)
                    error('교점이 존재하지 않습니다. 원이 서로 접하지 않습니다.');
                elseif d < abs(radius_theta_circle - radius_phi_circle)
                    error('교점이 존재하지 않습니다. 하나의 원이 다른 원 내부에 있습니다.');
                elseif d == 0 && radius_theta_circle == radius_phi_circle
                    error('무한한 교점이 존재합니다. 두 원이 동일합니다.');
                else
                    % 두 원의 교점 계산
                    a = (radius_theta_circle^2 - radius_phi_circle^2 + d^2) / (2 * d);
                    h = sqrt(radius_theta_circle^2 - a^2);

                    % 두 원의 교점 (x, y) 좌표 계산
                    x2 = center_xy + a * (center_yz - center_xy) / d;
                    y2 = 0;  % y 좌표는 동일한 축상에서

                    % 교점 두 개 계산
                    x_intersect1 = x2 + h * (0 - 0) / d;  % 교점 1의 x좌표
                    y_intersect1 = y2 + h;

                    x_intersect2 = x2 - h * (0 - 0) / d;  % 교점 2의 x좌표
                    y_intersect2 = y2 - h;

                    x_intersect = [x_intersect1, x_intersect2];
                    y_intersect = [y_intersect1, y_intersect2];
                end

                % 그래프 그리기
                hold(app.UIAxes, 'on');  % 기존 그래프 유지
                axis(app.UIAxes, 'equal');

                % sigma_1와 sigma_2를 양 끝점으로 하는 원
                plot(app.UIAxes, x_circle_xy, y_circle_xy, 'k', 'LineWidth', 2);

                % sigma_2와 sigma_3를 양 끝점으로 하는 원
                plot(app.UIAxes, x_circle_yz, y_circle_yz, 'k', 'LineWidth', 2);

                % sigma_1와 sigma_3를 양 끝점으로 하는 원
                plot(app.UIAxes, x_circle_xz, y_circle_xz, 'k', 'LineWidth', 2);

                % phi와 theta에 해당하는 점을 검은색 점으로 표시
                plot(app.UIAxes, x_phi, y_phi, 'o', 'MarkerSize', 5, 'MarkerFaceColor', 'blue');  % 검은색 점 (x_phi, y_phi)
                plot(app.UIAxes, x_theta, y_theta, 'o', 'MarkerSize', 5, 'MarkerFaceColor', 'blue');  % 검은색 점 (x_theta, y_theta)

                % x축과 y축 그리기
                xline(app.UIAxes, 0, 'k', 'LineWidth', 1);  % x축 추가 (검은색 점선)
                yline(app.UIAxes, 0, 'k', 'LineWidth', 1);  % y축 추가 (검은색 점선)

                % 점선으로 된 원 그리기
                plot(app.UIAxes, x_dashed_circle_theta, y_dashed_circle_theta, 'k--', 'LineWidth', 1);  % 점선 원 (x_theta, y_theta)
                plot(app.UIAxes, x_dashed_circle_phi, y_dashed_circle_phi, 'k--', 'LineWidth', 1);     % 점선 원 (x_phi, y_phi)

                % 두 원의 교점을 파란 점으로 표시
                plot(app.UIAxes, x_intersect, abs(y_intersect), 'o', 'MarkerSize', 10, 'MarkerFaceColor', 'red');

                
                % 축 설정
                xlabel(app.UIAxes, 'Normal Stress (MPa)');
                ylabel(app.UIAxes, 'Shear Stress (MPa)');
                title(app.UIAxes, '3D Mohr''s Circle');
                % 모든 원(실선 반원 + 점선 반원)의 x, y 좌표를 모아서
                all_x = [ x_circle_xy, x_circle_yz, x_circle_xz, ...
                    x_dashed_circle_theta, x_dashed_circle_phi ];
                all_y = [ y_circle_xy, y_circle_yz, y_circle_xz, ...
                    y_dashed_circle_theta, y_dashed_circle_phi ];
                
                
                posIdx = find(y_intersect >= 0);
                

                for k = posIdx
                    % 수직선
                    line( app.UIAxes, ...
                        [x_intersect(k), x_intersect(k)], [y_intersect(k), 0], ...
                        'Color','k','LineStyle','--','LineWidth',1, 'Clipping','on' );
                    % 수평선
                    line( app.UIAxes, ...
                        [x_intersect(k), 0], [y_intersect(k), y_intersect(k)], ...
                        'Color','k','LineStyle','--','LineWidth',1, 'Clipping','on' );
                    % 텍스트
                    text( app.UIAxes, x_intersect(k), 0,   ['  ', num2str(x_intersect(k))], ...
                        'VerticalAlignment','bottom','HorizontalAlignment','right' );
                    text( app.UIAxes, min(all_x),            y_intersect(k), ['  ', num2str(y_intersect(k))], ...
                        'VerticalAlignment','top',   'HorizontalAlignment','left' );
                end
                % (2) x축 범위는 σ3~σ1, y축 범위는 0~최대 y
                xlim(app.UIAxes, [ min(all_x), max(all_x) ]);
                ylim(app.UIAxes, [ 0,          max(all_y) ]);
                % (원 비율 유지하면서 보려면)
                axis(app.UIAxes, 'equal');
                % 텍스트를 오른쪽으로 조금 이동시키고, 글자 크기를 크게 설정
                offset = 0.5;  % 텍스트를 오른쪽으로 이동시키기 위한 오프셋 값
                fontSize = 14;  % 텍스트 크기

                % sigma_1, sigma_2, sigma_3 값을 x축에 텍스트로 표시
                text(app.UIAxes, sigma_1 + offset, 0, '\sigma_1', 'VerticalAlignment', 'top', 'HorizontalAlignment', 'left', 'FontSize', fontSize);
                text(app.UIAxes, sigma_2 + offset, 0, '\sigma_2', 'VerticalAlignment', 'top', 'HorizontalAlignment', 'left', 'FontSize', fontSize);
                text(app.UIAxes, sigma_3 + offset, 0, '\sigma_3', 'VerticalAlignment', 'top', 'HorizontalAlignment', 'left', 'FontSize', fontSize);

                % 교점 값을 Normal Stress와 Shear Stress 칸에 입력
                app.sigma_nMPaEditField.Value=x_intersect1;
                app.tau_nMPaEditField.Value=y_intersect1;

                hold(app.UIAxes, 'off');


                save('vars.mat', 'sigma_x','sigma_y','sigma_z','x_intersect1', 'n_x','n_y','n_z');

            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: Viewin3DButton
        function Viewin3DButtonPushed(app, event)

    try

        % Mohr_Sub 앱 생성
        subApp = Mohr_Sub;


    catch ex
        uialert(app.UIFigure, sprintf('Error: %s', ex.message), 'Error');
    end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D Mohr''s Circle')
            xlabel(app.UIAxes, 'Normal Stress (MPa)')
            ylabel(app.UIAxes, 'Shear Stress (MPa)')
            zlabel(app.UIAxes, 'Z')
            app.AddplotButton = uibutton(app.UIFigure, 'push');
            app.AddplotButton.ButtonPushedFcn = createCallbackFcn(app, @AddplotButtonPushed, true);
            app.AddplotButton.Text = 'Add plot';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DMohrsCircleLabel = uilabel(app.UIFigure);
            app.DMohrsCircleLabel.Text = '3D Mohr''s Circle';
            app.PrincipalStressesPanel = uipanel(app.UIFigure);
            app.PrincipalStressesPanel.Title = 'Principal Stresses';
            app.textittheta_3Label = uilabel(app.PrincipalStressesPanel);
            app.textittheta_3Label.Interpreter = 'latex';
            app.textittheta_3Label.Text = '$\sigma_{x}$ (MPa)';
            app.sigma_xMPaEditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.sigma_yMPaEditFieldLabel = uilabel(app.PrincipalStressesPanel);
            app.sigma_yMPaEditFieldLabel.Interpreter = 'latex';
            app.sigma_yMPaEditFieldLabel.Text = '$\sigma_{y}$ (MPa)';
            app.sigma_yMPaEditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.sigma_zMPaEditFieldLabel = uilabel(app.PrincipalStressesPanel);
            app.sigma_zMPaEditFieldLabel.Interpreter = 'latex';
            app.sigma_zMPaEditFieldLabel.Text = '$\sigma_{z}$ (MPa)';
            app.sigma_zMPaEditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.NormalvectorPanel = uipanel(app.UIFigure);
            app.NormalvectorPanel.Title = 'Normal vector';
            app.Label = uilabel(app.NormalvectorPanel);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '$\ n_{x}$';
            app.n_xEditField = uieditfield(app.NormalvectorPanel, 'numeric');
            app.n_yEditFieldLabel = uilabel(app.NormalvectorPanel);
            app.n_yEditFieldLabel.Interpreter = 'latex';
            app.n_yEditFieldLabel.Text = '$\ n_{y}$';
            app.n_yEditField = uieditfield(app.NormalvectorPanel, 'numeric');
            app.n_zEditFieldLabel = uilabel(app.NormalvectorPanel);
            app.n_zEditFieldLabel.Interpreter = 'latex';
            app.n_zEditFieldLabel.Text = '$\ n_{z}$';
            app.n_zEditField = uieditfield(app.NormalvectorPanel, 'numeric');
            app.StressinplanePanel = uipanel(app.UIFigure);
            app.StressinplanePanel.Title = 'Stress in plane';
            app.sigma_nMPaEditFieldLabel = uilabel(app.StressinplanePanel);
            app.sigma_nMPaEditFieldLabel.Interpreter = 'latex';
            app.sigma_nMPaEditFieldLabel.Text = '$\sigma_{n}$ (MPa)';
            app.sigma_nMPaEditField = uieditfield(app.StressinplanePanel, 'numeric');
            app.tau_nMPaEditFieldLabel = uilabel(app.StressinplanePanel);
            app.tau_nMPaEditFieldLabel.Interpreter = 'latex';
            app.tau_nMPaEditFieldLabel.Text = '$\tau_{n}$ (MPa)';
            app.tau_nMPaEditField = uieditfield(app.StressinplanePanel, 'numeric');
            app.Viewin3DButton = uibutton(app.UIFigure, 'push');
            app.Viewin3DButton.ButtonPushedFcn = createCallbackFcn(app, @Viewin3DButtonPushed, true);
            app.Viewin3DButton.Text = 'View in 3D';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mohr_Circle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end