classdef Mohr_Sub < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StressComponentsPanel  matlab.ui.container.Panel
        T_nMPaEditField        matlab.ui.control.EditField
        T_nMPaEditFieldLabel   matlab.ui.control.Label
        tau_nMPaEditField      matlab.ui.control.EditField
        tau_nMPaLabel          matlab.ui.control.Label
        sigma_nMPaEditField    matlab.ui.control.EditField
        sigma_nMPaLabel        matlab.ui.control.Label
        DMohrsCircleLabel      matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            try
                load('vars.mat', 'sigma_x','sigma_y','sigma_z','x_intersect1', 'n_x','n_y','n_z');
                hold(app.UIAxes, 'on');
                axis(app.UIAxes, 'equal');
                xlabel(app.UIAxes, 'sigma_x (MPa)');
                ylabel(app.UIAxes, 'sigma_y (MPa)');
                zlabel(app.UIAxes, 'sigma_z (MPa)');

                normal_stress=x_intersect1;
                unit_normal_vector=[n_x,n_y,n_z]/norm([n_x,n_y,n_z]);


                % 3D 보기로 설정
                view(app.UIAxes, 3);


                % 정육면체 크기 설정
                cube_size = max([sigma_x, sigma_y, sigma_z]);


                % 축 범위 설정 (음수 부분 제외)
                xlim(app.UIAxes, [-10, cube_size+10]);
                ylim(app.UIAxes, [-10, cube_size+10]);
                zlim(app.UIAxes, [-10, cube_size+10]);


                % 정육면체 그리기
                cube_vertices = [0 0 0; cube_size 0 0; cube_size cube_size 0; 0 cube_size 0;
                    0 0 cube_size; cube_size 0 cube_size; cube_size cube_size cube_size; 0 cube_size cube_size];

                % 정육면체의 면을 정의
                cube_faces = [1 2 3 4; 5 6 7 8; 1 2 6 5; 2 3 7 6;
                    3 4 8 7; 4 1 5 8];

                % 정육면체 테두리만 그리기 (검정색 테두리)
                patch(app.UIAxes, 'Vertices', cube_vertices, 'Faces', cube_faces, ...
                    'FaceColor', 'none', 'EdgeColor', 'black', 'LineWidth', 1.5);

                % x, y, z 축 그리기
                quiver3(app.UIAxes, 0, 0, 0, cube_size, 0, 0, 'k', 'LineWidth', 2);  % x축
                quiver3(app.UIAxes, 0, 0, 0, 0, cube_size, 0, 'k', 'LineWidth', 2);  % y축
                quiver3(app.UIAxes, 0, 0, 0, 0, 0, cube_size, 'k', 'LineWidth', 2);  % z축

                center_point = [cube_size, cube_size, cube_size] / 2;
                plane_size = cube_size * 0.5;

                [px, py] = meshgrid(linspace(-plane_size, plane_size, 10));
                pz = -(unit_normal_vector(1) * px + unit_normal_vector(2) * py) / unit_normal_vector(3);

                % 평면 좌표를 정육면체 내부로 제한
                px = max(min(px + center_point(1), cube_size), 0);
                py = max(min(py + center_point(2), cube_size), 0);
                pz = max(min(pz + center_point(3), cube_size), 0);


                % 평면 그리기 (빨간색으로 표시)
                surf(app.UIAxes, px, py, pz, 'FaceColor', 'red', 'FaceAlpha', 0.5);

                % Normal vector 그리기 (normal stress 크기를 반영)
                normal_stress_vector = normal_stress * unit_normal_vector;
                normal_stress_vector=-normal_stress_vector;
                quiver3(app.UIAxes, center_point(1), center_point(2), center_point(3), ...
                    0.5*normal_stress_vector(1),0.5* normal_stress_vector(2), 0.5*normal_stress_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'black');

                % Normal vector 끝에 레이블 추가
                text(app.UIAxes, center_point(1) + 0.5*normal_stress_vector(1), ...
                    center_point(2) + 0.5*normal_stress_vector(2), ...
                    center_point(3) + 0.5*normal_stress_vector(3), ...
                    '\sigma_n', 'FontSize', 10, 'Color', 'black');

                % Traction vector 계산
                stress_matrix = diag([-sigma_x, -sigma_y, -sigma_z]);
                traction_vector = stress_matrix * unit_normal_vector';


                % Traction vector 그리기
                quiver3(app.UIAxes, center_point(1), center_point(2), center_point(3), ...
                    0.5*traction_vector(1), 0.5*traction_vector(2), 0.5*traction_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'black');
                % Traction vector 끝에 레이블 추가
                text(app.UIAxes, center_point(1) + 0.5*traction_vector(1), ...
                    center_point(2) + 0.5*traction_vector(2), ...
                    center_point(3) + 0.5*traction_vector(3), ...
                    'T_n', 'FontSize', 10, 'Color', 'black');

                % Shear stress vector 계산 및 그리기
                shear_vector = traction_vector - normal_stress_vector';
                quiver3(app.UIAxes, center_point(1), center_point(2), center_point(3), ...
                    0.5*shear_vector(1), 0.5*shear_vector(2), 0.5*shear_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'black');

                % Shear vector 끝에 레이블 추가
                text(app.UIAxes, center_point(1) + 0.5*shear_vector(1), ...
                    center_point(2) + 0.5*shear_vector(2), ...
                    center_point(3) + 0.5*shear_vector(3), ...
                    '\tau_n', 'FontSize', 10, 'Color', 'black');

                %% sigma_x 벡터: yz 평면을 가리킴
                % 시작점: 중심점
                if sigma_x > 0
                sigma_x_vector = [10, 0, 0];  % 안쪽으로 들어가는 방향
                  
                    quiver3(app.UIAxes, -sigma_x_vector(1), center_point(2)+sigma_x_vector(2), center_point(3)+sigma_x_vector(3), ...
                    sigma_x_vector(1), sigma_x_vector(2), sigma_x_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'red');
                % 레이블 추가
                text(app.UIAxes, -sigma_x_vector(1), ...
                    center_point(2) + sigma_x_vector(2), ...
                    center_point(3) + sigma_x_vector(3), ...
                    '\sigma_x', 'FontSize', 10, 'Color', 'red');
                elseif sigma_x<0


                    sigma_x_vector = [-10, 0, 0];  % 바깥쪽으로 나오는 방향

                      quiver3(app.UIAxes, 0, center_point(2), center_point(3), ...
                    sigma_x_vector(1), sigma_x_vector(2), sigma_x_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'red');
                % 레이블 추가
                text(app.UIAxes, sigma_x_vector(1), ...
                    center_point(2) + sigma_x_vector(2), ...
                    center_point(3) + sigma_x_vector(3), ...
                    '\sigma_x', 'FontSize', 10, 'Color', 'red');
                end


                %% sigma_y 벡터: xz 평면을 가리킴
                % 시작점: 중심점
                if sigma_y > 0
                    sigma_y_vector = [0, 10, 0];  % 안쪽으로 들어가는 방향
                    
                    quiver3(app.UIAxes, center_point(1)+sigma_y_vector(1), -sigma_y_vector(2), center_point(3)+sigma_y_vector(3), ...
                    sigma_y_vector(1), sigma_y_vector(2), sigma_y_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'red');
                % 레이블 추가
                text(app.UIAxes, center_point(1) + sigma_y_vector(1), ...
                    -sigma_y_vector(2), ...
                    center_point(3) + sigma_y_vector(3), ...
                    '\sigma_y', 'FontSize', 10, 'Color', 'red');

                elseif sigma_y<0
                    sigma_y_vector = [0, -10, 0];  % 바깥쪽으로 나오는 방향

                    quiver3(app.UIAxes, center_point(1), 0, center_point(3), ...
                    sigma_y_vector(1), sigma_y_vector(2), sigma_y_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'red');
                % 레이블 추가
                text(app.UIAxes, center_point(1) + sigma_y_vector(1), ...
                    sigma_y_vector(2), ...
                    center_point(3) + sigma_y_vector(3), ...
                    '\sigma_y', 'FontSize', 10, 'Color', 'red');

                end


                %% sigma_z 벡터: xy 평면을 가리킴
                % 시작점: 중심점
                if sigma_z > 0
                    sigma_z_vector = [0, 0, 10];  % 안쪽으로 들어가는 방향

                         quiver3(app.UIAxes, center_point(1)+sigma_z_vector(1), center_point(2)+sigma_z_vector(2), -sigma_z_vector(3), ...
                    sigma_z_vector(1), sigma_z_vector(2), sigma_z_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'red');
                % 레이블 추가
                text(app.UIAxes, center_point(1) + sigma_z_vector(1), ...
                    center_point(2) + sigma_z_vector(2), ...
                    -sigma_z_vector(3), ...
                    '\sigma_z', 'FontSize', 10, 'Color', 'red');

                elseif sigma_z<0
                    sigma_z_vector = [0, 0, -10];  % 바깥쪽으로 나오는 방향
                         quiver3(app.UIAxes, center_point(1), center_point(2), 0, ...
                    sigma_z_vector(1), sigma_z_vector(2), sigma_z_vector(3), ...
                    'LineWidth', 2, 'MaxHeadSize', 2, 'Color', 'red');
                % 레이블 추가
                text(app.UIAxes, center_point(1) + sigma_z_vector(1), ...
                    center_point(2) + sigma_z_vector(2), ...
                    sigma_z_vector(3), ...
                    '\sigma_z', 'FontSize', 10, 'Color', 'red');
                end
                

                % xy 평면 (z=0) 칠하기 (반투명 파란색)
                patch(app.UIAxes, 'Vertices', [0 0 0; cube_size 0 0; cube_size cube_size 0; 0 cube_size 0], ...
                    'Faces', [1 2 3 4], 'FaceColor', 'blue', 'FaceAlpha', 0.2);

                % xy 평면 (z=cube_size) 칠하기 (반투명 파란색)
                patch(app.UIAxes, 'Vertices', [0 0 cube_size; cube_size 0 cube_size; cube_size cube_size cube_size; 0 cube_size cube_size], ...
                    'Faces', [1 2 3 4], 'FaceColor', 'blue', 'FaceAlpha', 0.2);

                % yz 평면 (x=0) 칠하기 (반투명 파란색)
                patch(app.UIAxes, 'Vertices', [0 0 0; 0 cube_size 0; 0 cube_size cube_size; 0 0 cube_size], ...
                    'Faces', [1 2 3 4], 'FaceColor', 'blue', 'FaceAlpha', 0.2);

                % yz 평면 (x=cube_size) 칠하기 (반투명 파란색)
                patch(app.UIAxes, 'Vertices', [cube_size 0 0; cube_size cube_size 0; cube_size cube_size cube_size; cube_size 0 cube_size], ...
                    'Faces', [1 2 3 4], 'FaceColor', 'blue', 'FaceAlpha', 0.2);

                % xz 평면 (y=0) 칠하기 (반투명 파란색)
                patch(app.UIAxes, 'Vertices', [0 0 0; cube_size 0 0; cube_size 0 cube_size; 0 0 cube_size], ...
                    'Faces', [1 2 3 4], 'FaceColor', 'blue', 'FaceAlpha', 0.2);

                % xz 평면 (y=cube_size) 칠하기 (반투명 파란색)
                patch(app.UIAxes, 'Vertices', [0 cube_size 0; cube_size cube_size 0; cube_size cube_size cube_size; 0 cube_size cube_size], ...
                    'Faces', [1 2 3 4], 'FaceColor', 'blue', 'FaceAlpha', 0.2);


                % 벡터 성분을 EditField에 입력
                app.T_nMPaEditField.Value = sprintf('( %.1f, %.1f, %.1f )', traction_vector(1), traction_vector(2), traction_vector(3));
                app.tau_nMPaEditField.Value = sprintf('( %.1f, %.1f, %.1f )', shear_vector(1), shear_vector(2), shear_vector(3));
                app.sigma_nMPaEditField.Value = sprintf('( %.1f, %.1f, %.1f )', normal_stress_vector(1), normal_stress_vector(2), normal_stress_vector(3));

                disp(size(px));  % px의 크기 출력
                disp(size(py));  % py의 크기 출력
                disp(size(pz));  % pz의 크기 출력
                disp(cube_size); % cube_size 값 출력

                % 또한, quiver3 함수 호출 전에 입력 값들을 확인할 수 있습니다.
                disp(normal_stress_vector);
                disp(traction_vector);
                disp(shear_vector);

            catch ex
                uialert(app.UIFigure, sprintf('Error: %s', ex.message), 'Error');
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DMohrsCircleLabel = uilabel(app.UIFigure);
            app.DMohrsCircleLabel.Text = '3D Mohr''s Circle';
            app.StressComponentsPanel = uipanel(app.UIFigure);
            app.StressComponentsPanel.Title = 'Stress Components';
            app.sigma_nMPaLabel = uilabel(app.StressComponentsPanel);
            app.sigma_nMPaLabel.Interpreter = 'latex';
            app.sigma_nMPaLabel.Text = '$\sigma_{n}$ (MPa)';
            app.sigma_nMPaEditField = uieditfield(app.StressComponentsPanel, 'text');
            app.tau_nMPaLabel = uilabel(app.StressComponentsPanel);
            app.tau_nMPaLabel.Interpreter = 'latex';
            app.tau_nMPaLabel.Text = '$\tau_{n}$ (MPa)';
            app.tau_nMPaEditField = uieditfield(app.StressComponentsPanel, 'text');
            app.T_nMPaEditFieldLabel = uilabel(app.StressComponentsPanel);
            app.T_nMPaEditFieldLabel.Interpreter = 'latex';
            app.T_nMPaEditFieldLabel.Text = '$T_{n}$ (MPa)';
            app.T_nMPaEditField = uieditfield(app.StressComponentsPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mohr_Sub

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end