classdef Simulator_int < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel7_3      matlab.ui.container.Panel
        Image_2         matlab.ui.control.Image
        stereoButton_3  matlab.ui.control.Button
        uipanel7_2      matlab.ui.container.Panel
        Image7          matlab.ui.control.Image
        stereoButton_2  matlab.ui.control.Button
        uipanel6        matlab.ui.container.Panel
        Image5          matlab.ui.control.Image
        Tempbutton      matlab.ui.control.Button
        uipanel3        matlab.ui.container.Panel
        Image3          matlab.ui.control.Image
        BSAbutton       matlab.ui.control.Button
        uipanel1        matlab.ui.container.Panel
        Image           matlab.ui.control.Image
        DFNbutton       matlab.ui.control.Button
        uipanel7        matlab.ui.container.Panel
        Image4          matlab.ui.control.Image
        stereoButton    matlab.ui.control.Button
        uipanel5        matlab.ui.container.Panel
        Image6          matlab.ui.control.Image
        HSbutton        matlab.ui.control.Button
        uipanel4        matlab.ui.container.Panel
        HFbutton        matlab.ui.control.Button
        Image2          matlab.ui.control.Image
        text2           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Simulator_OpeningFcn(app, varargin)
            % --- Executes just before Simulator is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Simulator (see VARARGIN)
            
            % Choose default command line output for Simulator
            handles.output = hObject;
            
            % Update handles structure
           
        end

        % Button pushed function: BSAbutton
        function BSAbutton_Callback(app, event)
            % --- Executes on button press in BSAbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BSAbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            BSA210831_v2
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: DFNbutton
        function DFNbutton_Callback(app, event)
            % --- Executes on button press in DFNbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to DFNbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            threeddfngui
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: HFbutton
        function HFbutton_Callback(app, event)
            % --- Executes on button press in HFbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to HFbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            HFsim
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: HSbutton
        function HSbutton_Callback(app, event)
            % --- Executes on button press in HSbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to HSbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            HSsim
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: Tempbutton
        function Tempbutton_Callback(app, event)
            % --- Executes on button press in Tempbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Tempbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            TherCal
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: stereoButton
        function stereoButton_Callback(app, event)
            % --- Executes on button press in stereoButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stereoButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            stereoProjection
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: stereoButton_3
        function stereoButton_3Pushed(app, event)
            try
            Mohr_Circle%하경이한테 받고 수정하기
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: stereoButton_2
        function stereoButton_2Pushed(app, event)
            try
            anisotropy
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Simulator';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Geomechanics Toolbox';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Tag = 'uipanel4';
            app.Image2 = uiimage(app.uipanel4);
            app.Image2.ImageSource = fullfile(pathToMLAPP, '5.jpg');
            app.HFbutton = uibutton(app.uipanel4, 'push');
            app.HFbutton.ButtonPushedFcn = createCallbackFcn(app, @HFbutton_Callback, true);
            app.HFbutton.Tag = 'HFbutton';
            app.HFbutton.Text = 'Hydrofracturing Estimation';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Tag = 'uipanel5';
            app.HSbutton = uibutton(app.uipanel5, 'push');
            app.HSbutton.ButtonPushedFcn = createCallbackFcn(app, @HSbutton_Callback, true);
            app.HSbutton.Tag = 'HSbutton';
            app.HSbutton.Text = 'Hydroshearing Estimation';
            app.Image6 = uiimage(app.uipanel5);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'b.jpg');
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Tag = 'uipanel7';
            app.stereoButton = uibutton(app.uipanel7, 'push');
            app.stereoButton.ButtonPushedFcn = createCallbackFcn(app, @stereoButton_Callback, true);
            app.stereoButton.Tag = 'stereoButton';
            app.stereoButton.Text = 'Stereographic Projection';
            app.Image4 = uiimage(app.uipanel7);
            app.Image4.ImageSource = fullfile(pathToMLAPP, '8.jpg');
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.DFNbutton = uibutton(app.uipanel1, 'push');
            app.DFNbutton.ButtonPushedFcn = createCallbackFcn(app, @DFNbutton_Callback, true);
            app.DFNbutton.Tag = 'DFNbutton';
            app.DFNbutton.Text = '3D DFN Generation';
            app.Image = uiimage(app.uipanel1);
            app.Image.ImageSource = fullfile(pathToMLAPP, '3.jpg');
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.BSAbutton = uibutton(app.uipanel3, 'push');
            app.BSAbutton.ButtonPushedFcn = createCallbackFcn(app, @BSAbutton_Callback, true);
            app.BSAbutton.Tag = 'BSAbutton';
            app.BSAbutton.Text = 'Borehole Stability';
            app.Image3 = uiimage(app.uipanel3);
            app.Image3.ImageSource = fullfile(pathToMLAPP, '7.jpg');
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Tag = 'uipanel6';
            app.Tempbutton = uibutton(app.uipanel6, 'push');
            app.Tempbutton.ButtonPushedFcn = createCallbackFcn(app, @Tempbutton_Callback, true);
            app.Tempbutton.Tag = 'Tempbutton';
            app.Tempbutton.Text = 'Temperature Prediction';
            app.Image5 = uiimage(app.uipanel6);
            app.Image5.ImageSource = fullfile(pathToMLAPP, '9.jpg');
            app.uipanel7_2 = uipanel(app.figure1);
            app.uipanel7_2.Tag = 'uipanel7';
            app.stereoButton_2 = uibutton(app.uipanel7_2, 'push');
            app.stereoButton_2.ButtonPushedFcn = createCallbackFcn(app, @stereoButton_2Pushed, true);
            app.stereoButton_2.Tag = 'stereoButton';
            app.stereoButton_2.Text = 'Strength Anisotropy';
            app.Image7 = uiimage(app.uipanel7_2);
            app.Image7.ImageSource = fullfile(pathToMLAPP, 'aniso.jpg');
            app.uipanel7_3 = uipanel(app.figure1);
            app.uipanel7_3.Tag = 'uipanel7';
            app.stereoButton_3 = uibutton(app.uipanel7_3, 'push');
            app.stereoButton_3.ButtonPushedFcn = createCallbackFcn(app, @stereoButton_3Pushed, true);
            app.stereoButton_3.Tag = 'stereoButton';
            app.stereoButton_3.Text = '3D Mohr Circle';
            app.Image_2 = uiimage(app.uipanel7_3);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, '스크린샷 2024-08-26 164945.jpg');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulator_int(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Simulator_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end