classdef Simulator_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel6      matlab.ui.container.Panel
        Tempbutton    matlab.ui.control.Button
        axes6         matlab.ui.control.UIAxes
        uipanel3      matlab.ui.container.Panel
        BSAbutton     matlab.ui.control.Button
        axes3         matlab.ui.control.UIAxes
        uipanel1      matlab.ui.container.Panel
        DFNbutton     matlab.ui.control.Button
        axes1         matlab.ui.control.UIAxes
        uipanel7      matlab.ui.container.Panel
        stereoButton  matlab.ui.control.Button
        axes7         matlab.ui.control.UIAxes
        uipanel5      matlab.ui.container.Panel
        HSbutton      matlab.ui.control.Button
        axes5         matlab.ui.control.UIAxes
        uipanel4      matlab.ui.container.Panel
        HFbutton      matlab.ui.control.Button
        axes4         matlab.ui.control.UIAxes
        text2         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Simulator_OpeningFcn(app, varargin)
            % --- Executes just before Simulator is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to Simulator (see VARARGIN)
            
            % Choose default command line output for Simulator
            handles.output = hObject;
            
            % Update handles structure
            try
            
            guidata(hObject, handles);
            [x,map]=imread('3.jpg');
            imshow(x, 'Parent', handles.axes1)
            [x,map]=imread('7.jpg');
            imshow(x, 'Parent', handles.axes3)
            [x,map]=imread('5.jpg');
            imshow(x, 'Parent', handles.axes4)
            [x,map]=imread('b.jpg');
            imshow(x, 'Parent', handles.axes5)
            [x,map]=imread('9.jpg');
            imshow(x, 'Parent', handles.axes6)
            [x,map]=imread('8.jpg');
            imshow(x, 'Parent', handles.axes7)
            % UIWAIT makes Simulator wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: BSAbutton
        function BSAbutton_Callback(app, event)
            % --- Executes on button press in BSAbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BSAbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            BSA210831_v2
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: DFNbutton
        function DFNbutton_Callback(app, event)
            % --- Executes on button press in DFNbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to DFNbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            threeddfngui
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: HFbutton
        function HFbutton_Callback(app, event)
            % --- Executes on button press in HFbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to HFbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            HFsim
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: HSbutton
        function HSbutton_Callback(app, event)
            % --- Executes on button press in HSbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to HSbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            HSsim
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: Tempbutton
        function Tempbutton_Callback(app, event)
            % --- Executes on button press in Tempbutton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to Tempbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            TherCal
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end

        % Button pushed function: stereoButton
        function stereoButton_Callback(app, event)
            % --- Executes on button press in stereoButton.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stereoButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            try
            stereoProjection
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'Simulator.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Simulator';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Geomechanics Toolbox';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Tag = 'uipanel4';
            app.axes4 = uiaxes(app.uipanel4);
            app.axes4.Tag = 'axes4';
            app.HFbutton = uibutton(app.uipanel4, 'push');
            app.HFbutton.ButtonPushedFcn = createCallbackFcn(app, @HFbutton_Callback, true);
            app.HFbutton.Tag = 'HFbutton';
            app.HFbutton.Text = 'Hydrofracturing Estimation';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Tag = 'uipanel5';
            app.axes5 = uiaxes(app.uipanel5);
            app.axes5.Tag = 'axes5';
            app.HSbutton = uibutton(app.uipanel5, 'push');
            app.HSbutton.ButtonPushedFcn = createCallbackFcn(app, @HSbutton_Callback, true);
            app.HSbutton.Tag = 'HSbutton';
            app.HSbutton.Text = 'Hydroshearing Estimation';
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Tag = 'uipanel7';
            app.axes7 = uiaxes(app.uipanel7);
            app.axes7.Tag = 'axes7';
            app.stereoButton = uibutton(app.uipanel7, 'push');
            app.stereoButton.ButtonPushedFcn = createCallbackFcn(app, @stereoButton_Callback, true);
            app.stereoButton.Tag = 'stereoButton';
            app.stereoButton.Text = 'Stereographic Projection';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.DFNbutton = uibutton(app.uipanel1, 'push');
            app.DFNbutton.ButtonPushedFcn = createCallbackFcn(app, @DFNbutton_Callback, true);
            app.DFNbutton.Tag = 'DFNbutton';
            app.DFNbutton.Text = '3D DFN Generation';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.axes3 = uiaxes(app.uipanel3);
            app.axes3.Tag = 'axes3';
            app.BSAbutton = uibutton(app.uipanel3, 'push');
            app.BSAbutton.ButtonPushedFcn = createCallbackFcn(app, @BSAbutton_Callback, true);
            app.BSAbutton.Tag = 'BSAbutton';
            app.BSAbutton.Text = 'Borehole Stability Analysis';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Tag = 'uipanel6';
            app.axes6 = uiaxes(app.uipanel6);
            app.axes6.Tag = 'axes6';
            app.Tempbutton = uibutton(app.uipanel6, 'push');
            app.Tempbutton.ButtonPushedFcn = createCallbackFcn(app, @Tempbutton_Callback, true);
            app.Tempbutton.Tag = 'Tempbutton';
            app.Tempbutton.Text = 'Temperature Prediction';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Simulator_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Simulator_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end