classdef BSA_App_v4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FIleMenu                        matlab.ui.container.Menu
        MeshSettingMenu                 matlab.ui.container.Menu
        ResetButton                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        ThermalPropertiesPanel          matlab.ui.container.Panel
        ButtonGroup_5                   matlab.ui.container.ButtonGroup
        ThermalEffectSwitch             matlab.ui.control.Switch
        ThermalEffectSwitchLabel        matlab.ui.control.Label
        KLabel                          matlab.ui.control.Label
        ExpansionCoefEditField          matlab.ui.control.NumericEditField
        ExpansionCoefEditFieldLabel     matlab.ui.control.Label
        TemperaturechangeEditField      matlab.ui.control.NumericEditField
        TemperaturechangeEditFieldLabel  matlab.ui.control.Label
        BoundaryConditionPanel          matlab.ui.container.Panel
        tau_zxEditField                 matlab.ui.control.NumericEditField
        tau_zxEditFieldLabel            matlab.ui.control.Label
        MPaLabel_11                     matlab.ui.control.Label
        tau_yzEditField                 matlab.ui.control.NumericEditField
        tau_yzEditFieldLabel            matlab.ui.control.Label
        MPaLabel_10                     matlab.ui.control.Label
        tau_xyEditField                 matlab.ui.control.NumericEditField
        tau_xyLabel                     matlab.ui.control.Label
        MPaLabel_9                      matlab.ui.control.Label
        sigma_zEditField                matlab.ui.control.NumericEditField
        sigma_zEditFieldLabel           matlab.ui.control.Label
        MPaLabel_8                      matlab.ui.control.Label
        sigma_yEditField                matlab.ui.control.NumericEditField
        sigma_yEditFieldLabel           matlab.ui.control.Label
        MPaLabel_7                      matlab.ui.control.Label
        MPaLabel_6                      matlab.ui.control.Label
        PorePressureEditField           matlab.ui.control.NumericEditField
        PorePressureEditField_2Label    matlab.ui.control.Label
        ButtonGroup_4                   matlab.ui.container.ButtonGroup
        FluidInjectionSwitch            matlab.ui.control.Switch
        FluidInjectionSwitchLabel       matlab.ui.control.Label
        MPaLabel_5                      matlab.ui.control.Label
        InjectionPressureEditField      matlab.ui.control.NumericEditField
        InjectionPressureEditFieldLabel  matlab.ui.control.Label
        MPaLabel                        matlab.ui.control.Label
        sigma_xEditField                matlab.ui.control.NumericEditField
        sigma_xEditFieldLabel           matlab.ui.control.Label
        SelectAnalysisPanel             matlab.ui.container.Panel
        ButtonGroup_3                   matlab.ui.container.ButtonGroup
        FEMButton                       matlab.ui.control.RadioButton
        AnalyticSolutionButton          matlab.ui.control.RadioButton
        UITable                         matlab.ui.control.Table
        DropDown                        matlab.ui.control.DropDown
        ElasticParametersPanel          matlab.ui.container.Panel
        ApplyButton                     matlab.ui.control.Button
        vEditField_5                    matlab.ui.control.NumericEditField
        vEditFieldLabel_5               matlab.ui.control.Label
        vEditField_4                    matlab.ui.control.NumericEditField
        vEditFieldLabel_4               matlab.ui.control.Label
        vEditField_3                    matlab.ui.control.NumericEditField
        vEditFieldLabel_3               matlab.ui.control.Label
        vEditField_2                    matlab.ui.control.NumericEditField
        vEditFieldLabel_2               matlab.ui.control.Label
        vEditField                      matlab.ui.control.NumericEditField
        vLabel                          matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        OrthotropicButton               matlab.ui.control.RadioButton
        TransverselyIsotropicButton     matlab.ui.control.RadioButton
        IsotropicButton                 matlab.ui.control.RadioButton
        BoreholeinformationPanel        matlab.ui.container.Panel
        degreeLabel_2                   matlab.ui.control.Label
        degreeLabel                     matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        AzimuthAngleEditField           matlab.ui.control.NumericEditField
        AzimuthAngleEditFieldLabel      matlab.ui.control.Label
        PolarAngleEditField             matlab.ui.control.NumericEditField
        PolarAngleEditFieldLabel        matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        BoreholeOriType                 matlab.ui.container.ButtonGroup
        AllOrientationButton            matlab.ui.control.RadioButton
        SingleOrientationButton         matlab.ui.control.RadioButton
        BoreholeStabilityAnalyzerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
       % Description
    end
    
    methods (Access = private)
        
        % Single Orientation function
        function Callone(app)
            app.RadiusEditField.Value = 0.1;
            app.RadiusEditField.Editable = 'off';
            app.PolarAngleEditField.Value = 0;
            app.PolarAngleEditField.Editable = 'off';
            app.AzimuthAngleEditField.Value = 0;
            app.AzimuthAngleEditField.Editable = 'off';

            app.RadiusEditField.Editable = 'on';
            app.PolarAngleEditField.Editable = 'on';
            app.AzimuthAngleEditField.Editable = 'on';
            app.TransverselyIsotropicButton.Enable = 'on';
            app.OrthotropicButton.Enable = 'on';
            app.FEMButton.Enable = 'on';
        end

        % All Orientation function
        function Callall(app)
            app.RadiusEditField.Value = 0.1;
            app.RadiusEditField.Editable = 'off';
            app.PolarAngleEditField.Value = 0;
            app.PolarAngleEditField.Editable = 'off';
            app.AzimuthAngleEditField.Value = 0;
            app.AzimuthAngleEditField.Editable = 'off';

            app.TransverselyIsotropicButton.Enable = 'off';
            app.OrthotropicButton.Enable = 'off';
            app.FEMButton.Enable = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UITable.Data = zeros(6);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            UITableDisplayDataChanged(app, event)
        end

        % Selection changed function: ButtonGroup
        function IsotrophyTypeChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;

            if selectedButton==app.IsotropicButton
                app.vEditField_2.Enable="on";
                app.vEditField.Enable="off";
                app.vEditField_3.Enable="on";
                app.vEditField_4.Enable="off";
                app.vEditField_5.Enable="off";
                app.UITable.Enable = 'off';

            end

            if selectedButton==app.TransverselyIsotropicButton
                app.vEditField_2.Enable="on";
                app.vEditField.Enable="on";
                app.vEditField_3.Enable="on";
                app.vEditField_4.Enable="on";
                app.vEditField_5.Enable="on";
                app.UITable.Enable = 'off';

            end

            if selectedButton==app.OrthotropicButton
                app.vEditField_2.Enable="off";
                app.vEditField.Enable="off";
                app.vEditField_3.Enable="off";
                app.vEditField_4.Enable="off";
                app.vEditField_5.Enable="off";
                app.UITable.Enable = 'on';

            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            v = app.vEditField_2.Value;
            v_ = app.vEditField.Value;
            E = app.vEditField_3.Value;
            E_ = app.vEditField_4.Value;
            G = app.vEditField_5.Value;
            
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton==app.IsotropicButton
                app.UITable.Data = [1/E -v/E -v/E 0 0 0; -v/E 1/E -v/E 0 0 0; -v/E -v/E 1/E 0 0 0; 0 0 0 2*(1+v)/E 0 0; 0 0 0 0 2*(1+v)/E 0; 0 0 0 0 0 2*(1+v)/E];
            end

            if selectedButton==app.TransverselyIsotropicButton  
                app.UITable.Data = [1/E -v/E -v_/E_ 0 0 0; -v/E 1/E -v_/E_ 0 0 0; -v_/E_ -v_/E_ 1/E_ 0 0 0; 0 0 0 1/G 0 0; 0 0 0 0 1/G 0; 0 0 0 0 0 2*(1+v)/E];
            end
           
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            try
                if app.ButtonGroup_3.SelectedObject==app.AnalyticSolutionButton
                    if app.BoreholeOriType.SelectedObject==app.SingleOrientationButton
                        secondwindow = SIngleOri2nd_v2();
                        secondwindow.UIFigure.Visible = 'on';

                        tic

                        % Check which material type button is selected
                        if app.IsotropicButton.Value==1
                            % Code for transversely isotropic case
                            v = app.vEditField_2.Value;
                            E = app.vEditField_3.Value;
                            Gmat = [1/E -v/E -v/E 0 0 0; -v/E 1/E -v/E 0 0 0; -v/E -v/E 1/E 0 0 0; 0 0 0 2*(1+v)/E 0 0; 0 0 0 0 2*(1+v)/E 0; 0 0 0 0 0 2*(1+v)/E];


                        elseif app.TransverselyIsotropicButton.Value==1
                            % Code for transversely isotropic case
                            v = app.vEditField_2.Value;
                            v_ = app.vEditField.Value;
                            E = app.vEditField_3.Value;
                            E_ = app.vEditField_4.Value;
                            G = app.vEditField_5.Value;
                            Gmat = [1/E -v/E -v_/E_ 0 0 0; -v/E 1/E -v_/E_ 0 0 0; -v_/E_ -v_/E_ 1/E_ 0 0 0; 0 0 0 1/G 0 0; 0 0 0 0 1/G 0; 0 0 0 0 0 2*(1+v)/E];


                        elseif app.OrthotropicButton.Value==1
                            % Code for orthotropic case
                            Gmat = app.UITable.Data;

                        end

                        if strcmp(app.DropDown.Value, '3D Elastic Modulus Matrix(Voigt)')
                            % Code for 3D Elastic Modulus Matrix(Voigt)
                            amatte=inv(Gmat)*10^6;

                        elseif strcmp(app.DropDown.Value, '3D Compliance Matrix(Voigt)')
                            % Code for 3D Compliance Matrix(Voigt)
                            amatte=Gmat/10^6;
                        end

                        % Pore pressure value from the edit field
                        % stresses considering pore pressure
                        Gpp = app.PorePressureEditField.Value;
                        Gsxi = (app.sigma_xEditField.Value - Gpp) * 10^6;
                        Gsyi = (app.sigma_yEditField.Value - Gpp) * 10^6;
                        Gszi = (app.sigma_zEditField.Value - Gpp) * 10^6;
                        Gsxyi = app.tau_xyEditField.Value*10^6;
                        Gsyzi = app.tau_yzEditField.Value*10^6;
                        Gsxzi = app.tau_zxEditField.Value*10^6;

                        Gfp = 0;
                        % Gfp는 delta P를 의미
                        % Fluid button이 켜지는 조건문
                        if strcmp(app.FluidInjectionSwitch.Value, 'On')
                            Gmud = app.InjectionPressureEditField.Value;
                            Gfp = (Gmud - Gpp) * 10^6;
                        end

                        Gtc = 0;
                        Gec = 0;
                        % Gtc와 Gec는 각각 thermal change와 expansion coefficient 의미
                        % Thermal button이 켜지는 조건문
                        if strcmp(app.ThermalEffectSwitch.Value, 'On')
                            Gtc = app.TemperaturechangeEditField.Value;
                            Gec = app.ExpansionCoefEditField.Value;
                        end
                        theffect=Gtc*Gec*(1/amatte(1,1))/(1+amatte(2,1)/amatte(1,1));
                        Gr = app.RadiusEditField.Value;
                        Gpol = app.PolarAngleEditField.Value*2*pi/360;
                        Gazi = app.AzimuthAngleEditField.Value*2*pi/360;


                        % Matrix Transform by Ong(1994)
                        L1=cos(Gpol)*cos(Gazi);
                        L2=-sin(Gazi);
                        L3=sin(Gpol)*cos(Gazi);
                        M1=cos(Gpol)*sin(Gazi);
                        M2=cos(Gazi);
                        M3=sin(Gazi)*sin(Gpol);
                        N1=-sin(Gpol);
                        N2=0;
                        N3=cos(Gpol);

                        Tsig=[L1^2 M1^2 N1^2 2*M1*N1 2*N1*L1 2*L1*M1;
                            L2^2 M2^2 N2^2 2*M2*N2 2*N2*L2 2*L2*M2;
                            L3^2 M3^2 N3^2 2*M3*N3 2*N3*L3 2*L3*M3;
                            L2*L3 M2*M3 N2*N3 M2*N3+M3*N2 N2*L3+N3*L2 L2*M3+L3*M2;
                            L3*L1 M3*M1 N3*N1 M1*N3+M3*N1 N1*L3+N3*L1 L1*M3+L3*M1;
                            L1*L2 M1*M2 N1*N2 M1*N2+M2*N1 N1*L2+N2*L1 L1*M2+L2*M1];

                        devst=Tsig*[Gsxi;Gsyi;Gszi;Gsxzi;Gsyzi;Gsxyi];              %시추공 좌표계로 변환된 응력값
                        Gsx=devst(1);
                        Gsy=devst(2);
                        Gsz=devst(3);
                        Gtyz=devst(4);
                        Gtxz=devst(5);
                        Gtxy=devst(6);

                        amat=transpose(Tsig)*amatte*Tsig;
                        if app.AnalyticSolutionButton.Value ==1

                            bmat=zeros([6,6]); %reduced strain coefficient by Lekhnitskii
                            for i=1:6
                                for j=1:6
                                    bmat(i,j)=amat(i,j)-(amat(i,3)*amat(j,3))/amat(3,3);
                                end
                            end
                            % syms symmu
                            % eqn = (bmat(1,1)*symmu^4-2*bmat(1,6)*symmu^3+(2*bmat(1,2)+bmat(6,6))*symmu^2 -2*bmat(2,6)*symmu+bmat(2,2))*(bmat(5,5)*symmu^2-2*bmat(4,5)*symmu+bmat(4,4))-(bmat(1,5)*symmu^3-(bmat(1,4)+bmat(5,6))*symmu^2+(bmat(2,5)+bmat(4,6))*symmu-bmat(2,4))^2 ==0;
                            % solmu=solve(eqn, symmu);

                            %Ong(1994), equation 3.3.4-3.3.5
                            p = [ bmat(1,1)*bmat(5,5)-bmat(1,5)^2 ...
                                2*bmat(1,5)*(bmat(1,4)+bmat(5,6))-2*bmat(1,1)*bmat(4,5)-2*bmat(1,6)*bmat(5,5) ...
                                bmat(1,1)*bmat(4,4)+4*bmat(1,6)*bmat(4,5)+bmat(5,5)*(2*bmat(1,2)+bmat(6,6))-2*bmat(1,5)*(bmat(2,5)+bmat(4,6))-(bmat(1,4)+bmat(5,6))^2 ...
                                2*bmat(1,5)*bmat(2,4)+2*(bmat(1,4)+bmat(5,6))*(bmat(2,5)+bmat(4,6))-2*bmat(1,6)*bmat(4,4)-2*bmat(4,5)*(2*bmat(1,2)+bmat(6,6))-2*bmat(2,6)*bmat(5,5) ...
                                bmat(4,4)*(2*bmat(1,2)+bmat(6,6))+4*bmat(2,6)*bmat(4,5)+bmat(2,2)*bmat(5,5)-2*bmat(2,4)*(bmat(1,4)+bmat(5,6))-(bmat(2,5)+bmat(4,6))^2 ...
                                2*bmat(2,4)*(bmat(2,5)+bmat(4,6))-2*bmat(2,6)*bmat(4,4)-2*bmat(2,2)*bmat(4,5) ...
                                bmat(2,2)*bmat(4,4)-bmat(2,4)^2 ];
                            solmu = roots(p);

                            doublesolmu=double(solmu);
                            solj=1;
                            tempmu=zeros([1,6]);
                            for solk=1:6
                                if imag(doublesolmu(solk))>0
                                    tempmu(solj)=doublesolmu(solk);
                                    solj=solj+1;
                                end
                            end

                            mu(1)=1.0001*tempmu(1);
                            mu(2)=1.0002*tempmu(2);
                            mu(3)=1.0003*tempmu(3);
                            sort(mu, 'descend');


                            lbd(1)=-(bmat(1,5)*mu(1)^3-(bmat(1,4)+bmat(5,6))*mu(1)^2+(bmat(2,5)+bmat(4,6))*mu(1)-bmat(2,4))/(bmat(5,5)*mu(1)^2-2*bmat(4,5)*mu(1)+bmat(4,4));
                            lbd(2)=-(bmat(1,5)*mu(2)^3-(bmat(1,4)+bmat(5,6))*mu(2)^2+(bmat(2,5)+bmat(4,6))*mu(2)-bmat(2,4))/(bmat(5,5)*mu(2)^2-2*bmat(4,5)*mu(2)+bmat(4,4));
                            lbd(3)=-(bmat(1,5)*mu(3)^3-(bmat(1,4)+bmat(5,6))*mu(3)^2+(bmat(2,5)+bmat(4,6))*mu(3)-bmat(2,4))/(bmat(1,1)*mu(3)^4-2*bmat(1,6)*mu(3)^3+(2*bmat(1,2)+bmat(6,6))*mu(3)^2 -2*bmat(2,6)*mu(3)+bmat(2,2));


                            %r=Gr:0.001:10*Gr;
                            r=(1:0.05:10)*Gr;
                            r=r';
                            theta=0: 1/360 *pi/2 : 2*pi;
                            theta=theta';
                            R=Gr;
                            Del=mu(2)-mu(1)+lbd(3)*lbd(2)*(mu(1)-mu(3)) +lbd(1)*lbd(3)*(mu(3)-mu(2));
                            p1=bmat(1,1)*mu(1)^2 +bmat(1,2)-bmat(1,6)*mu(1)+lbd(1)*(bmat(1,5)*mu(1)-bmat(1,4));
                            p2=bmat(1,1)*mu(2)^2+bmat(1,2)-bmat(1,6)*mu(2)+lbd(2)*(bmat(1,5)*mu(2)-bmat(1,4));
                            p3=lbd(3)*(bmat(1,1)*mu(3)^2+bmat(1,2)-bmat(1,6)*mu(3))+bmat(1,5)*mu(3)-bmat(1,4);
                            q1=bmat(1,2)*mu(1)+bmat(2,2)/mu(1)-bmat(2,6)+lbd(1)*(bmat(2,5)-bmat(2,4)/mu(1));
                            q2=bmat(1,2)*mu(2)+bmat(2,2)/mu(2)-bmat(2,6)+lbd(2)*(bmat(2,5)-bmat(2,4)/mu(2));
                            q3=lbd(3)*(bmat(1,2)*mu(3)+bmat(2,2)/mu(3) -bmat(2,6))+bmat(2,5)-bmat(2,4)/mu(3);

                            z1=zeros([length(r),length(theta)]);
                            z2=zeros([length(r),length(theta)]);
                            z3=zeros([length(r),length(theta)]);
                            deter1=zeros([length(r),length(theta)]);
                            deter2=zeros([length(r),length(theta)]);
                            deter3=zeros([length(r),length(theta)]);
                            detert1=zeros([length(r),length(theta)]);
                            detert2=zeros([length(r),length(theta)]);
                            detert3=zeros([length(r),length(theta)]);
                            zeta1=zeros([length(r),length(theta)]);
                            zeta2=zeros([length(r),length(theta)]);
                            zeta3=zeros([length(r),length(theta)]);
                            Phi1=zeros([length(r),length(theta)]);
                            Phi2=zeros([length(r),length(theta)]);
                            Phi3=zeros([length(r),length(theta)]);
                            Txy=zeros([length(r),length(theta)]);
                            Txz=zeros([length(r),length(theta)]);
                            Tyz=zeros([length(r),length(theta)]);
                            Sxx=zeros([length(r),length(theta)]);
                            Syy=zeros([length(r),length(theta)]);
                            Szz=zeros([length(r),length(theta)]);
                            pphi1=zeros([length(r),length(theta)]);
                            pphi2=zeros([length(r),length(theta)]);
                            pphi3=zeros([length(r),length(theta)]);
                            dispxx=zeros([length(r),length(theta)]);
                            dispyy=zeros([length(r),length(theta)]);
                            epxx=zeros([length(r),length(theta)]);
                            epyy=zeros([length(r),length(theta)]);
                            Sr=zeros([length(r),length(theta)]);
                            St=zeros([length(r),length(theta)]);
                            Sz=zeros([length(r),length(theta)]);
                            Trt=zeros([length(r),length(theta)]);
                            Trz=zeros([length(r),length(theta)]);
                            Ttz=zeros([length(r),length(theta)]);
                            Sp1=zeros([length(r),length(theta)]);
                            Sp2=zeros([length(r),length(theta)]);
                            Sp3=zeros([length(r),length(theta)]);
                            thermalst = zeros([length(r),length(theta)]);

                            %Following Ong(1994), 3.4
                            for countr=1:length(r)
                                stsign1=1;
                                stsign2=1;
                                stsign3=1;
                                detsign1=1;
                                detsign2=1;
                                detsign3=1;
                                for countth=1:length(theta)
                                    z1(countr,countth)=r(countr)*(cos(theta(countth)) + mu(1)*sin(theta(countth)));
                                    z2(countr,countth)=r(countr)*(cos(theta(countth)) + mu(2)*sin(theta(countth)));
                                    z3(countr,countth)=r(countr)*(cos(theta(countth)) + mu(3)*sin(theta(countth)));
                                    deter1(countr,countth)=(z1(countr,countth)/R)^2 -1 -mu(1)^2;
                                    deter2(countr,countth)=(z2(countr,countth)/R)^2 -1 -mu(2)^2;
                                    deter3(countr,countth)=(z3(countr,countth)/R)^2 -1 -mu(3)^2;
                                    if stsign1==1
                                        if imag(deter1(countr,countth))>0
                                            stsign1=-1;
                                        end
                                    end
                                    if stsign1==-1
                                        if imag(deter1(countr,countth))<0
                                            stsign1=+1;
                                            detsign1=-detsign1;
                                        end
                                    end
                                    if stsign2==1
                                        if imag(deter2(countr,countth))>0
                                            stsign2=-1;
                                        end
                                    end
                                    if stsign2==-1
                                        if imag(deter2(countr,countth))<0
                                            stsign2=+1;
                                            detsign2=-detsign2;
                                        end
                                    end
                                    if stsign3==1
                                        if imag(deter3(countr,countth))>0
                                            stsign3=-1;
                                        end
                                    end
                                    if stsign3==-1
                                        if imag(deter3(countr,countth))<0
                                            stsign3=+1;
                                            detsign3=-detsign3;
                                        end
                                    end
                                    detert1(countr,countth)=detsign1*sqrt((z1(countr,countth)/R)^2 -1 -mu(1)^2);
                                    detert2(countr,countth)=detsign2*sqrt((z2(countr,countth)/R)^2 -1 -mu(2)^2);
                                    detert3(countr,countth)=detsign3*sqrt((z3(countr,countth)/R)^2 -1 -mu(3)^2);
                                    zeta1(countr,countth)=(z1(countr,countth)/R + detert1(countr,countth))/(1-1i*mu(1)); %Ong(1994), eq 3.4.7
                                    zeta2(countr,countth)=(z2(countr,countth)/R + detert2(countr,countth))/(1-1i*mu(2));
                                    zeta3(countr,countth)=(z3(countr,countth)/R + detert3(countr,countth))/(1-1i*mu(3));
                                    Phi1(countr,countth)=-(1/(2*Del*zeta1(countr,countth)*detert1(countr,countth)))    *((1i*Gtxy - Gsy+Gfp)*(mu(2)-lbd(2)*lbd(3)*mu(3))+(Gtxy-1i*Gsx+1i*Gfp)*(lbd(2)*lbd(3)-1)+(Gtyz-1i*Gtxz)*lbd(3)*(mu(3)-mu(2))); %Ong(1994), eq 3.4.11
                                    Phi2(countr,countth)=-(1/(2*Del*zeta2(countr,countth)*detert2(countr,countth)))    *((1i*Gtxy - Gsy+Gfp)*(lbd(1)*lbd(3)*mu(3)-mu(1))+(Gtxy-1i*Gsx+1i*Gfp)*(1-lbd(1)*lbd(3))+(Gtyz-1i*Gtxz)*lbd(3)*(mu(1)-mu(3)));
                                    Phi3(countr,countth)=-(1/(2*Del*zeta3(countr,countth)*detert3(countr,countth)))    *((1i*Gtxy - Gsy+Gfp)*(mu(1)*lbd(2)-mu(2)*lbd(1))+(Gtxy-1i*Gsx+1i*Gfp)*(lbd(1)-lbd(2))+(Gtyz-1i*Gtxz)*(mu(2)-mu(1)));
                                    Sxx(countr,countth)=Gsx+2*real(mu(1)^2*Phi1(countr,countth)+mu(2)^2*Phi2(countr,countth)+(lbd(3)*mu(3)^2)*Phi3(countr,countth)); %Ong(1994), eq 3.3.20
                                    Syy(countr,countth)=Gsy+2*real(Phi1(countr,countth)+Phi2(countr,countth)+lbd(3)*Phi3(countr,countth));
                                    Txy(countr,countth)=Gtxy-2*real(mu(1)*Phi1(countr,countth)+mu(2)*Phi2(countr,countth)+(lbd(3)*mu(3)*Phi3(countr,countth)));
                                    Txz(countr,countth)=Gtxz+2*real(mu(1)*lbd(1)*Phi1(countr,countth)+mu(2)*lbd(2)*Phi2(countr,countth)+mu(3)*Phi3(countr,countth));
                                    Tyz(countr,countth)=Gtyz-2*real(lbd(1)*Phi1(countr,countth)+lbd(2)*Phi2(countr,countth)+Phi3(countr,countth));
                                    Szz(countr,countth)=Gsz-(1/amat(3,3))*(amat(3,1)*(Sxx(countr,countth)-Gsx)+amat(3,2)*(Syy(countr,countth)-Gsy)+amat(3,4)*(Tyz(countr,countth)-Gtyz)+amat(3,5)*(Txz(countr,countth)-Gtxz)+amat(3,6)*(Txy(countr,countth)-Gtxy));
                                    pphi1(countr,countth)=R/(2*Del*zeta1(countr,countth)) *((1i*Gtxy - Gsy+Gfp)*(mu(2)-lbd(2)*lbd(3)*mu(3))+(Gtxy-1i*Gsx+1i*Gfp)*(lbd(2)*lbd(3)-1)+(Gtyz-1i*Gsz)*lbd(3)*(mu(3)-mu(2)));
                                    pphi2(countr,countth)=R/(2*Del*zeta2(countr,countth)) *((1i*Gtxy - Gsy+Gfp)*(lbd(1)*lbd(3)*mu(3)-mu(1))+(Gtxy-1i*Gsx+1i*Gfp)*(1-lbd(1)*lbd(3))+(Gtyz-1i*Gsz)*lbd(3)*(mu(1)-mu(3)));
                                    pphi3(countr,countth)=R/(2*Del*zeta3(countr,countth)) *((1i*Gtxy - Gsy+Gfp)*(mu(1)*lbd(2)-mu(2)*lbd(1))+(Gtxy-1i*Gsx+1i*Gfp)*(lbd(1)-lbd(2))+(Gtyz-1i*Gsz)*(mu(2)-mu(1)));
                                    dispxx(countr,countth)=2*real(p1*pphi1(countr,countth)+p2*pphi2(countr,countth)+p3*pphi3(countr,countth));
                                    dispyy(countr,countth)=2*real(q1*pphi1(countr,countth)+q2*pphi2(countr,countth)+q3*pphi3(countr,countth));
                                end
                            end

                            % Thermal expansion
                            ep=zeros(1,6);
                            eptemp=zeros(6,6);

                            for countr=1:length(r)
                                for countth=1:length(theta)
                                    L11=cos(theta(countth));
                                    L22=-sin(theta(countth));
                                    L33=0;
                                    M11=sin(theta(countth));
                                    M22=cos(theta(countth));
                                    M33=0;
                                    N11=0;
                                    N22=0;
                                    N33=1;

                                    eptrans=[L11^2 M11^2 N11^2 2*M11*N11 2*N11*L11 2*L11*M11;
                                        L22^2 M22^2 N22^2 2*M22*N22 2*N22*L22 2*L22*M22;
                                        L33^2 M33^2 N33^2 2*M33*N33 2*N33*L33 2*L33*M33;
                                        L22*L33 M22*M33 N22*N33 M22*N33+M33*N22 N22*L33+N33*L22 L22*M33+L33*M22;
                                        L33*L11 M33*M11 N33*N11 M11*N33+M33*N11 N11*L33+N33*L11 L11*M33+L33*M11;
                                        L11*L22 M11*M22 N11*N22 M11*N22+M22*N11 N11*L22+N22*L11 L11*M22+L22*M11];

                                    epthertemp=transpose(eptrans)*amat*eptrans;
                                    epther=epthertemp(2:3,2:3);
                                    stthertemp=mldivide(epther,[Gtc*Gec;Gtc*Gec]);
                                    thermalst(countr,countth)=stthertemp(1);
                                    stther=mldivide(eptrans,[0;stthertemp(1);stthertemp(2);0;0;0]);

                                    Sxx(countr,countth)=Sxx(countr,countth)+stther(1);
                                    Syy(countr,countth)=Syy(countr,countth)+stther(2);
                                    Szz(countr,countth)=Szz(countr,countth)+stther(3);
                                    Tyz(countr,countth)=Tyz(countr,countth)+stther(4);
                                    Txz(countr,countth)=Txz(countr,countth)+stther(5);
                                    Txy(countr,countth)=Txy(countr,countth)+stther(6);
                                end
                            end


                            for countr=1:length(r)
                                for countth=1:length(theta)
                                    temp=[cos(theta(countth)) sin(theta(countth)) 0;
                                        -sin(theta(countth)) cos(theta(countth)) 0;
                                        0 0 1]*[Sxx(countr,countth) Txy(countr,countth) Txz(countr,countth);
                                        Txy(countr,countth) Syy(countr,countth) Tyz(countr,countth);
                                        Txz(countr,countth) Tyz(countr,countth) Szz(countr,countth)]*[cos(theta(countth)) -sin(theta(countth)) 0;
                                        sin(theta(countth)) cos(theta(countth)) 0;
                                        0 0 1];
                                    Sr(countr,countth)=temp(1,1);
                                    St(countr,countth)=temp(2,2);
                                    Sz(countr,countth)=temp(3,3);
                                    Trt(countr,countth)=temp(1,2);
                                    Trz(countr,countth)=temp(1,3);
                                    Ttz(countr,countth)=temp(2,3);
                                end
                            end

                            for i=1:length(Sr(:,1))
                                for j=1:length(Sr(1,:))
                                    Aeig=eig([Sr(i,j) Trt(i,j) Trz(i,j);
                                        Trt(i,j) St(i,j) Ttz(i,j);
                                        Trz(i,j) Ttz(i,j) Sz(i,j)]);
                                    Beig = sort(Aeig);
                                    Sp1(i,j) = Beig(3); %Max
                                    Sp2(i,j) = Beig(2); %Mid
                                    Sp3(i,j) = Beig(1); %Min
                                end
                            end
                            [gridtheta, gridr]=meshgrid(theta,r);
                            nodetheta=gridtheta;
                            noder=gridr;
                        end
                    elseif app.BoreholeOriType.SelectedObject==app.AllOrientationButton
                        if app.ButtonGroup.SelectedObject~=app.IsotropicButton
                            errordlg('Only isotropic cases are available for "all direction"')
                        end
                        if app.ButtonGroup_3.SelectedObject~=app.AnalyticSolutionButton
                            errordlg('Only analytic solution is available for "all direction"')
                            return
                        end

                        secondwindow=RequiredUCS_AllOri();
                        secondwindow.UIFigure.Visible = 'on';
                        thirdwindow=BoreholeBreakoutOri_All();
                        thirdwindow.UIFigure.Visible = 'on';
                    end

                    save('vars.mat','Gr','Sr','St','gridr','gridtheta','Sz','Trt','Trz','Ttz','Sxx','Syy',...
                        'Szz','Txy','Tyz','Txz','noder','nodetheta','dispxx','dispyy',...
                        'Sp1','Sp2','Sp3')
                elseif app.ButtonGroup_3.SelectedObject==app.FEMButton
                    %FEM
                    %초기값 60,60 갱신된거 없나 체크
                    rmesh=60;
                    thmesh=60;
                    load("meshvar.mat","rmesh","thmesh")
                    %test
                    % msgbox(sprintf('%d %d',rmesh,thmesh));
                    
                end
            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end

           
        end

        % Value changed function: ThermalEffectSwitch
        function ThermalEffectSwitchValueChanged(app, event)
            
            try
                onoff = app.ThermalEffectSwitch.Value; % Assuming 'ThermalEffectSwitch' is the component for the toggle
        
                if strcmp(onoff, 'Off')
                    app.TemperaturechangeEditField.Value = 0; 
                    app.TemperaturechangeEditField.Editable = 'off';
                    app.ExpansionCoefEditField.Editable = 'off'; 
                elseif strcmp(onoff, 'On')
                    app.TemperaturechangeEditField.Editable = 'on';
                    app.ExpansionCoefEditField.Editable = 'on';
                end
            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end
        end

        % Value changed function: FluidInjectionSwitch
        function FluidInjectionSwitchValueChanged(app, event)
                      
            try
                onoff = app.FluidInjectionSwitch.Value; % Assuming 'FluidInjectionSwitch' is the component for the toggle
        
                if strcmp(onoff, 'Off')
                    app.InjectionPressureEditField.Value = 0; % Assuming 'EditField5' is the corresponding edit field
                    app.InjectionPressureEditField.Editable = 'off';
                elseif strcmp(onoff, 'On')
                    app.InjectionPressureEditField.Editable = 'on';
                end
            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end
            
        end

        % Selection changed function: BoreholeOriType
        function BoreholeOriTypeSelectionChanged(app, event)
            selectedButton = app.BoreholeOriType.SelectedObject;
            
            if selectedButton == app.SingleOrientationButton
                Callone(app);
                
            elseif selectedButton == app.AllOrientationButton
                Callall(app);
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %reset radio buttons
            app.BoreholeOriType.SelectedObject = app.SingleOrientationButton;
            app.ButtonGroup.SelectedObject=app.IsotropicButton;
            app.ButtonGroup_3.SelectedObject=app.AnalyticSolutionButton;
            

            %reset edit fields
            app.RadiusEditField.Value=0;
            app.PolarAngleEditField.Value=0;
            app.RadiusEditField.Value=0;
            app.AzimuthAngleEditField.Value=0;

            app.sigma_xEditField.Value=0;
            app.sigma_yEditField.Value=0;
            app.sigma_zEditField.Value=0;
            app.tau_xyEditField.Value=0;
            app.tau_yzEditField.Value=0;
            app.tau_zxEditField.Value=0;
            app.PorePressureEditField.Value=0;

            app.vEditField_2.Value=0.25;
            app.vEditField.Value=0.25;
            app.vEditField_3.Value=100;
            app.vEditField_4.Value=100;
            app.vEditField_5.Value=100;

            app.InjectionPressureEditField.Value=0;
            app.TemperaturechangeEditField.Value=0;
            app.ExpansionCoefEditField.Value=0;

            %reset switch
            app.FluidInjectionSwitch.Value='Off';
            app.ThermalEffectSwitch.Value='Off';


        end

        % Menu selected function: MeshSettingMenu
        function MeshSettingMenuSelected(app, event)
            try
                secondwindow = Meshfigure();
                secondwindow.UIFigure.Visible = 'on';
            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FIleMenu = uimenu(app.UIFigure);
            app.FIleMenu.Text = 'FIle';
            app.MeshSettingMenu = uimenu(app.FIleMenu);
            app.MeshSettingMenu.MenuSelectedFcn = createCallbackFcn(app, @MeshSettingMenuSelected, true);
            app.MeshSettingMenu.Text = 'Mesh Setting';
            app.BoreholeStabilityAnalyzerLabel = uilabel(app.UIFigure);
            app.BoreholeStabilityAnalyzerLabel.Text = 'Borehole Stability Analyzer';
            app.BoreholeinformationPanel = uipanel(app.UIFigure);
            app.BoreholeinformationPanel.Title = 'Borehole information';
            app.BoreholeOriType = uibuttongroup(app.BoreholeinformationPanel);
            app.BoreholeOriType.SelectionChangedFcn = createCallbackFcn(app, @BoreholeOriTypeSelectionChanged, true);
            app.SingleOrientationButton = uiradiobutton(app.BoreholeOriType);
            app.SingleOrientationButton.Text = 'Single Orientation';
            app.SingleOrientationButton.Value = true;
            app.AllOrientationButton = uiradiobutton(app.BoreholeOriType);
            app.AllOrientationButton.Text = 'All Orientation';
            app.RadiusEditFieldLabel = uilabel(app.BoreholeinformationPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.BoreholeinformationPanel, 'numeric');
            app.RadiusEditField.Value = 0.1;
            app.PolarAngleEditFieldLabel = uilabel(app.BoreholeinformationPanel);
            app.PolarAngleEditFieldLabel.Text = 'Polar Angle';
            app.PolarAngleEditField = uieditfield(app.BoreholeinformationPanel, 'numeric');
            app.AzimuthAngleEditFieldLabel = uilabel(app.BoreholeinformationPanel);
            app.AzimuthAngleEditFieldLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleEditField = uieditfield(app.BoreholeinformationPanel, 'numeric');
            app.mLabel = uilabel(app.BoreholeinformationPanel);
            app.mLabel.Text = 'm';
            app.degreeLabel = uilabel(app.BoreholeinformationPanel);
            app.degreeLabel.Text = 'degree';
            app.degreeLabel_2 = uilabel(app.BoreholeinformationPanel);
            app.degreeLabel_2.Text = 'degree';
            app.ElasticParametersPanel = uipanel(app.UIFigure);
            app.ElasticParametersPanel.Title = 'Elastic Parameters';
            app.ButtonGroup = uibuttongroup(app.ElasticParametersPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IsotrophyTypeChanged, true);
            app.IsotropicButton = uiradiobutton(app.ButtonGroup);
            app.IsotropicButton.Text = 'Isotropic';
            app.IsotropicButton.Value = true;
            app.TransverselyIsotropicButton = uiradiobutton(app.ButtonGroup);
            app.TransverselyIsotropicButton.Text = 'Transversely Isotropic';
            app.OrthotropicButton = uiradiobutton(app.ButtonGroup);
            app.OrthotropicButton.Text = 'Orthotropic';
            app.vLabel = uilabel(app.ElasticParametersPanel);
            app.vLabel.Text = 'v''';
            app.vEditField = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.vEditField.Enable = 'off';
            app.vEditField.Value = 0.25;
            app.vEditFieldLabel_2 = uilabel(app.ElasticParametersPanel);
            app.vEditFieldLabel_2.Text = 'v';
            app.vEditField_2 = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.vEditField_2.Value = 0.25;
            app.vEditFieldLabel_3 = uilabel(app.ElasticParametersPanel);
            app.vEditFieldLabel_3.Text = 'E';
            app.vEditField_3 = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.vEditField_3.Value = 100;
            app.vEditFieldLabel_4 = uilabel(app.ElasticParametersPanel);
            app.vEditFieldLabel_4.Text = 'E''';
            app.vEditField_4 = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.vEditField_4.Enable = 'off';
            app.vEditField_4.Value = 100;
            app.vEditFieldLabel_5 = uilabel(app.ElasticParametersPanel);
            app.vEditFieldLabel_5.Text = 'G''';
            app.vEditField_5 = uieditfield(app.ElasticParametersPanel, 'numeric');
            app.vEditField_5.Enable = 'off';
            app.vEditField_5.Value = 100;
            app.ApplyButton = uibutton(app.ElasticParametersPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'3D Elastic Modulus Matrix(Voigt)', '3D Compliance Matrix(Voigt)'};
            app.DropDown.Value = '3D Elastic Modulus Matrix(Voigt)';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.UITable.Enable = 'off';
            app.SelectAnalysisPanel = uipanel(app.UIFigure);
            app.SelectAnalysisPanel.Title = 'Select Analysis';
            app.ButtonGroup_3 = uibuttongroup(app.SelectAnalysisPanel);
            app.AnalyticSolutionButton = uiradiobutton(app.ButtonGroup_3);
            app.AnalyticSolutionButton.Text = 'Analytic Solution';
            app.AnalyticSolutionButton.Value = true;
            app.FEMButton = uiradiobutton(app.ButtonGroup_3);
            app.FEMButton.Text = 'FEM';
            app.BoundaryConditionPanel = uipanel(app.UIFigure);
            app.BoundaryConditionPanel.Title = 'Boundary Condition';
            app.sigma_xEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.sigma_xEditFieldLabel.Interpreter = 'tex';
            app.sigma_xEditFieldLabel.Text = '\sigma_x';
            app.sigma_xEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.MPaLabel = uilabel(app.BoundaryConditionPanel);
            app.MPaLabel.Text = 'MPa';
            app.ButtonGroup_4 = uibuttongroup(app.BoundaryConditionPanel);
            app.InjectionPressureEditFieldLabel = uilabel(app.ButtonGroup_4);
            app.InjectionPressureEditFieldLabel.Text = 'Injection Pressure';
            app.InjectionPressureEditField = uieditfield(app.ButtonGroup_4, 'numeric');
            app.InjectionPressureEditField.Editable = 'off';
            app.MPaLabel_5 = uilabel(app.ButtonGroup_4);
            app.MPaLabel_5.Text = 'MPa';
            app.FluidInjectionSwitchLabel = uilabel(app.ButtonGroup_4);
            app.FluidInjectionSwitchLabel.Text = 'Fluid Injection';
            app.FluidInjectionSwitch = uiswitch(app.ButtonGroup_4, 'slider');
            app.FluidInjectionSwitch.ValueChangedFcn = createCallbackFcn(app, @FluidInjectionSwitchValueChanged, true);
            app.PorePressureEditField_2Label = uilabel(app.BoundaryConditionPanel);
            app.PorePressureEditField_2Label.Text = 'Pore Pressure';
            app.PorePressureEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.MPaLabel_6 = uilabel(app.BoundaryConditionPanel);
            app.MPaLabel_6.Text = 'MPa';
            app.MPaLabel_7 = uilabel(app.BoundaryConditionPanel);
            app.MPaLabel_7.Text = 'MPa';
            app.sigma_yEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.sigma_yEditFieldLabel.Interpreter = 'tex';
            app.sigma_yEditFieldLabel.Text = '\sigma_y';
            app.sigma_yEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.MPaLabel_8 = uilabel(app.BoundaryConditionPanel);
            app.MPaLabel_8.Text = 'MPa';
            app.sigma_zEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.sigma_zEditFieldLabel.Interpreter = 'tex';
            app.sigma_zEditFieldLabel.Text = '\sigma_z';
            app.sigma_zEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.MPaLabel_9 = uilabel(app.BoundaryConditionPanel);
            app.MPaLabel_9.Text = 'MPa';
            app.tau_xyLabel = uilabel(app.BoundaryConditionPanel);
            app.tau_xyLabel.Interpreter = 'tex';
            app.tau_xyLabel.Text = '\tau_{xy}';
            app.tau_xyEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.MPaLabel_10 = uilabel(app.BoundaryConditionPanel);
            app.MPaLabel_10.Text = 'MPa';
            app.tau_yzEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.tau_yzEditFieldLabel.Interpreter = 'tex';
            app.tau_yzEditFieldLabel.Text = '\tau_{yz}';
            app.tau_yzEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.MPaLabel_11 = uilabel(app.BoundaryConditionPanel);
            app.MPaLabel_11.Text = 'MPa';
            app.tau_zxEditFieldLabel = uilabel(app.BoundaryConditionPanel);
            app.tau_zxEditFieldLabel.Interpreter = 'tex';
            app.tau_zxEditFieldLabel.Text = '\tau_{zx}';
            app.tau_zxEditField = uieditfield(app.BoundaryConditionPanel, 'numeric');
            app.ThermalPropertiesPanel = uipanel(app.UIFigure);
            app.ThermalPropertiesPanel.Title = 'Thermal Properties';
            app.ButtonGroup_5 = uibuttongroup(app.ThermalPropertiesPanel);
            app.TemperaturechangeEditFieldLabel = uilabel(app.ButtonGroup_5);
            app.TemperaturechangeEditFieldLabel.Text = 'Temperature change';
            app.TemperaturechangeEditField = uieditfield(app.ButtonGroup_5, 'numeric');
            app.TemperaturechangeEditField.Editable = 'off';
            app.ExpansionCoefEditFieldLabel = uilabel(app.ButtonGroup_5);
            app.ExpansionCoefEditFieldLabel.Text = 'Expansion Coef.';
            app.ExpansionCoefEditField = uieditfield(app.ButtonGroup_5, 'numeric');
            app.ExpansionCoefEditField.Editable = 'off';
            app.KLabel = uilabel(app.ButtonGroup_5);
            app.KLabel.Text = 'K';
            app.ThermalEffectSwitchLabel = uilabel(app.ButtonGroup_5);
            app.ThermalEffectSwitchLabel.Text = 'Thermal Effect';
            app.ThermalEffectSwitch = uiswitch(app.ButtonGroup_5, 'slider');
            app.ThermalEffectSwitch.ValueChangedFcn = createCallbackFcn(app, @ThermalEffectSwitchValueChanged, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BSA_App_v4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end