classdef Meshfigure < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ApplyButton              matlab.ui.control.Button
        ThetameshEditField       matlab.ui.control.NumericEditField
        ThetameshEditFieldLabel  matlab.ui.control.Label
        RmeshEditField           matlab.ui.control.NumericEditField
        RmeshEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            try
               
                rmesh=app.RmeshEditField.Value;
                thmesh=app.ThetameshEditField.Value;
                save('meshvar.mat',"rmesh","thmesh");
                delete(app.UIFigure);
                
            catch ex
                errmsg = ex.stack.line;
                msgbox([{'BSA.m' 'Error found in line ' num2str(errmsg)} {ex.message}]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RmeshEditFieldLabel = uilabel(app.UIFigure);
            app.RmeshEditFieldLabel.Text = 'R mesh';
            app.RmeshEditField = uieditfield(app.UIFigure, 'numeric');
            app.RmeshEditField.Value = 60;
            app.ThetameshEditFieldLabel = uilabel(app.UIFigure);
            app.ThetameshEditFieldLabel.Text = 'Theta mesh';
            app.ThetameshEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThetameshEditField.Value = 60;
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Meshfigure

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end