classdef Mohr_Circle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        range090Label             matlab.ui.control.Label
        EditField_2               matlab.ui.control.NumericEditField
        EditField                 matlab.ui.control.NumericEditField
        phiSlider                 matlab.ui.control.Slider
        phiSliderLabel            matlab.ui.control.Label
        thetaSlider               matlab.ui.control.Slider
        thetaSliderLabel          matlab.ui.control.Label
        PrincipalStressesPanel    matlab.ui.container.Panel
        sigma_zMPaEditField       matlab.ui.control.NumericEditField
        sigma_zMPaEditFieldLabel  matlab.ui.control.Label
        sigma_yMPaEditField       matlab.ui.control.NumericEditField
        sigma_yMPaEditFieldLabel  matlab.ui.control.Label
        sigma_xMPaEditField       matlab.ui.control.NumericEditField
        textittheta_3Label        matlab.ui.control.Label
        DMohrsCircleLabel         matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        AddplotButton             matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function inside = isInMohrCircle(~,x, y, center12, radius12, center23, radius23, center31, radius31)
            % Check if a point (x, y) is inside all three Mohr circles
            inside = ((x - center12)^2 + y^2 >= radius12^2) && ...
                ((x - center23)^2 + y^2 >= radius23^2) && ...
                ((x - center31)^2 + y^2 <= radius31^2);
        end

        function plot3DMohrCircle(app,sigmaA, sigmaB, sigmaC, theta, phi)
            % sigmaA, sigmaB, sigmaC: Input stresses (not necessarily ordered)

            % Sort the input stresses to get sigma1 >= sigma2 >= sigma3
            sigma = sort([sigmaA, sigmaB, sigmaC], 'descend');
            sigma1 = sigma(1);
            sigma2 = sigma(2);
            sigma3 = sigma(3);

            % Calculate the centers and radii of the Mohr circles
            center12 = (sigma1 + sigma2) / 2;
            radius12 = (sigma1 - sigma2) / 2;

            center23 = (sigma2 + sigma3) / 2;
            radius23 = (sigma2 - sigma3) / 2;

            center31 = (sigma3 + sigma1) / 2;
            radius31 = (sigma1 - sigma3) / 2;

            % Create the theta values for plotting
            angle = linspace(0, 2*pi, 360);

            % for deviated plane
            point_A = [center23 - radius23 * cosd(2*theta), radius23 * sind(2*theta)];
            point_B = [center12 + radius12 * cosd(2*phi), radius12 * sind(2*phi)];
            cent_A = [center12,0];
            cent_B = [center23,0];
            rad_A = sqrt((point_A(1)-cent_A(1))^2+(point_A(2)-cent_A(2))^2);
            rad_B = sqrt((point_B(1)-cent_B(1))^2+(point_B(2)-cent_B(2))^2);


            % Plot Mohr Circle sigma1-sigma2
            hold(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            plot(app.UIAxes,center12 + radius12*cos(angle), radius12*sin(angle), 'k-', 'LineWidth', 2);
            
            % Plot Mohr Circle sigma2-sigma3
            plot(app.UIAxes,center23 + radius23*cos(angle), radius23*sin(angle), 'k-', 'LineWidth', 2);

            % Plot Mohr Circle sigma3-sigma1
            plot(app.UIAxes,center31 + radius31*cos(angle), radius31*sin(angle), 'k-', 'LineWidth', 2);

            %점선

            for i = 1:length(angle)
                x_A = cent_A(1) + rad_A*cos(angle(i));
                y_A = rad_A*sin(angle(i));

                x_B = cent_B(1) + rad_B*cos(angle(i));
                y_B = rad_B*sin(angle(i));

                % Check if points lie within all three Mohr circles
                if app.isInMohrCircle(x_A, y_A, center12, radius12, center23, radius23, center31, radius31)
                    plot(app.UIAxes,x_A, y_A, 'r.','MarkerSize', 1.5);
                end

                if app.isInMohrCircle(x_B, y_B, center12, radius12, center23, radius23, center31, radius31)
                    plot(app.UIAxes,x_B, y_B, 'g.','MarkerSize', 1.5);

                end
            end

            % Labels and title
            xlabel(app.UIAxes, '\sigma');
            ylabel(app.UIAxes, '\tau');
            title(app.UIAxes, '3D Mohr Circles');
            grid(app.UIAxes, 'on');
            axis(app.UIAxes, 'equal');
            xline(app.UIAxes, 0, 'k--', 'LineWidth', 1);  % x축 추가 (검은색 점선)
            yline(app.UIAxes, 0, 'k--', 'LineWidth', 1);  % y축 추가 (검은색 점선)
            % Setting axis limits
            xlim(app.UIAxes, [sigma3 - 10, sigma1 + 10]);
            ylim(app.UIAxes, [0, 1.333*radius31 ]);

            % Label sigma1, sigma2, sigma3 on the plot
            text(app.UIAxes, sigma1, 0, '\sigma_1', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', 'FontSize', 12);
            text(app.UIAxes, sigma2, 0, '\sigma_2', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', 'FontSize', 12);
            text(app.UIAxes, sigma3, 0, '\sigma_3', 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right', 'FontSize', 12);


            hold off;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddplotButton
        function AddplotButtonPushed(app, event)
            try
               
                plot3DMohrCircle(app,app.sigma_xMPaEditField.Value, ...
                    app.sigma_yMPaEditField.Value,... 
                    app.sigma_zMPaEditField.Value, ...
                    app.thetaSlider.Value, ...
                    app.phiSlider.Value)
%{
                theta=app.thetaSlider.Value;
                phi=app.phiSlider.Value;

                sigma_x = app.sigma_xMPaEditField.Value;
                sigma_y = app.sigma_yMPaEditField.Value;
                sigma_z = app.sigma_zMPaEditField.Value;
                

                center_xy = (sigma_x + sigma_y) / 2;
                radius_xy = abs(sigma_y - sigma_x) / 2;
                center_yz = (sigma_y + sigma_z) / 2;
                radius_yz = abs(sigma_z - sigma_y) / 2;
                center_xz = (sigma_x + sigma_z) / 2;
                radius_xz = abs(sigma_z - sigma_x) / 2;


                % 원을 그리기 위한 각도 (0에서 2*pi까지)
                theta_circle = linspace(0, 2*pi, 100);

                % 동경에 해당하는 점 계산 (반시계 방향으로 phi만큼 회전)
                x_phi = center_xy + radius_xy * cos(2*phi);
                y_phi = radius_xy * sin(2*phi);

                % 동경에 해당하는 점 계산 (시계 방향으로 theta만큼 회전)
                x_theta = center_yz - radius_yz * cos(2*theta);  % 
                y_theta = radius_yz * sin(2*theta);

                % 점선으로 된 원 추가 계산
                % sigma_x와 sigma_y를 지름으로 하는 원의 중심을 중심으로 하고 (x_theta, y_theta)를 지나는 원
                radius_theta_circle = sqrt((x_theta - center_xy)^2 + (y_theta - 0)^2);
                x_dashed_circle_theta = center_xy + radius_theta_circle * cos(theta_circle);
                y_dashed_circle_theta = radius_theta_circle * sin(theta_circle);

                % sigma_y와 sigma_z를 지름으로 하는 원의 중심을 중심으로 하고 (x_phi, y_phi)를 지나는 원
                radius_phi_circle = sqrt((x_phi - center_yz)^2 + (y_phi - 0)^2);
                x_dashed_circle_phi = center_yz + radius_phi_circle * cos(theta_circle);
                y_dashed_circle_phi = radius_phi_circle * sin(theta_circle);
                
                d = abs(center_xy - center_yz);  % 두 원의 중심 사이의 거리
                if d > (radius_theta_circle + radius_phi_circle)
                    error('교점이 존재하지 않습니다. 원이 서로 접하지 않습니다.');
                elseif d < abs(radius_theta_circle - radius_phi_circle)
                    error('교점이 존재하지 않습니다. 하나의 원이 다른 원 내부에 있습니다.');
                elseif d == 0 && radius_theta_circle == radius_phi_circle
                    error('무한한 교점이 존재합니다. 두 원이 동일합니다.');
                else
                    % 두 원의 교점 계산
                    a = (radius_theta_circle^2 - radius_phi_circle^2 + d^2) / (2 * d);
                    h = sqrt(radius_theta_circle^2 - a^2);

                    % 두 원의 교점 (x, y) 좌표 계산
                    x2 = center_xy + a * (center_yz - center_xy) / d;
                    y2 = 0;  % y 좌표는 동일한 축상에서

                    % 교점 두 개 계산
                    x_intersect1 = x2 + h * (0 - 0) / d;  % 교점 1의 x좌표
                    y_intersect1 = y2 + h;

                    x_intersect2 = x2 - h * (0 - 0) / d;  % 교점 2의 x좌표
                    y_intersect2 = y2 - h;

                    x_intersect = x_intersect1;
                    y_intersect = y_intersect1;
                end

                plot(app.UIAxes, x_intersect, y_intersect, 'ko', 'MarkerSize', 10, 'MarkerFaceColor', 'k');

                %}
                % 입력값
                theta=app.thetaSlider.Value;
                phi=app.phiSlider.Value;
                %{
                n_x = app.n_xEditField.Value;
                n_y = app.n_yEditField.Value;
                n_z = app.n_zEditField.Value;
                
                sigma_x = app.sigma_xMPaEditField.Value;
                sigma_y = app.sigma_yMPaEditField.Value;
                sigma_z = app.sigma_zMPaEditField.Value;

                %{
                벡터 크기 계산
                size_n = sqrt(n_x^2 + n_y^2 + n_z^2);

                %
                각도 phi와 theta 계산
                phi = acos(n_x / size_n);  % arccos -> acos (MATLAB 함수)
                theta = acos(n_z / size_n);
                %}

                % 원을 그리기 위한 각도 (0에서 2*pi까지)
                theta_circle = linspace(0, 2*pi, 100);

                % sigma_x와 sigma_y를 양 끝점으로 하는 원
                center_xy = (sigma_x + sigma_y) / 2;
                radius_xy = abs(sigma_y - sigma_x) / 2;
                x_circle_xy = center_xy + radius_xy * cos(theta_circle);
                y_circle_xy = radius_xy * sin(theta_circle);

                % sigma_y와 sigma_z를 양 끝점으로 하는 원
                center_yz = (sigma_y + sigma_z) / 2;
                radius_yz = abs(sigma_z - sigma_y) / 2;
                x_circle_yz = center_yz + radius_yz * cos(theta_circle);
                y_circle_yz = radius_yz * sin(theta_circle);

                % sigma_x와 sigma_z를 양 끝점으로 하는 원
                center_xz = (sigma_x + sigma_z) / 2;
                radius_xz = abs(sigma_z - sigma_x) / 2;
                x_circle_xz = center_xz + radius_xz * cos(theta_circle);
                y_circle_xz = radius_xz * sin(theta_circle);

                % 동경에 해당하는 점 계산 (반시계 방향으로 phi만큼 회전)
                x_phi = center_xy + radius_xy * cos(2*phi);
                y_phi = radius_xy * sin(2*phi);

                % 동경에 해당하는 점 계산 (시계 방향으로 theta만큼 회전)
                x_theta = center_yz - radius_yz * cos(2*theta);  % 
                y_theta = radius_yz * sin(2*theta);

                % 점선으로 된 원 추가 계산
                % sigma_x와 sigma_y를 지름으로 하는 원의 중심을 중심으로 하고 (x_theta, y_theta)를 지나는 원
                radius_theta_circle = sqrt((x_theta - center_xy)^2 + (y_theta - 0)^2);
                x_dashed_circle_theta = center_xy + radius_theta_circle * cos(theta_circle);
                y_dashed_circle_theta = radius_theta_circle * sin(theta_circle);

                % sigma_y와 sigma_z를 지름으로 하는 원의 중심을 중심으로 하고 (x_phi, y_phi)를 지나는 원
                radius_phi_circle = sqrt((x_phi - center_yz)^2 + (y_phi - 0)^2);
                x_dashed_circle_phi = center_yz + radius_phi_circle * cos(theta_circle);
                y_dashed_circle_phi = radius_phi_circle * sin(theta_circle);

                % 두 원의 교점 계산: 수치적으로 계산
                % 원1: (x - center_xy)^2 + y^2 = radius_theta_circle^2
                % 원2: (x - center_yz)^2 + y^2 = radius_phi_circle^2

                % 거리와 중간 값 계산
                d = abs(center_xy - center_yz);  % 두 원의 중심 사이의 거리
                if d > (radius_theta_circle + radius_phi_circle)
                    error('교점이 존재하지 않습니다. 원이 서로 접하지 않습니다.');
                elseif d < abs(radius_theta_circle - radius_phi_circle)
                    error('교점이 존재하지 않습니다. 하나의 원이 다른 원 내부에 있습니다.');
                elseif d == 0 && radius_theta_circle == radius_phi_circle
                    error('무한한 교점이 존재합니다. 두 원이 동일합니다.');
                else
                    % 두 원의 교점 계산
                    a = (radius_theta_circle^2 - radius_phi_circle^2 + d^2) / (2 * d);
                    h = sqrt(radius_theta_circle^2 - a^2);

                    % 두 원의 교점 (x, y) 좌표 계산
                    x2 = center_xy + a * (center_yz - center_xy) / d;
                    y2 = 0;  % y 좌표는 동일한 축상에서

                    % 교점 두 개 계산
                    x_intersect1 = x2 + h * (0 - 0) / d;  % 교점 1의 x좌표
                    y_intersect1 = y2 + h;

                    x_intersect2 = x2 - h * (0 - 0) / d;  % 교점 2의 x좌표
                    y_intersect2 = y2 - h;

                    x_intersect = [x_intersect1, x_intersect2];
                    y_intersect = [y_intersect1, y_intersect2];
                end

                % 그래프 그리기
                hold(app.UIAxes, 'on');  % 기존 그래프 유지
                axis(app.UIAxes, 'equal');

                % sigma_x와 sigma_y를 양 끝점으로 하는 원
                plot(app.UIAxes, x_circle_xy, y_circle_xy, 'r', 'LineWidth', 2);

                % sigma_y와 sigma_z를 양 끝점으로 하는 원
                plot(app.UIAxes, x_circle_yz, y_circle_yz, 'g', 'LineWidth', 2);

                % sigma_x와 sigma_z를 양 끝점으로 하는 원
                plot(app.UIAxes, x_circle_xz, y_circle_xz, 'b', 'LineWidth', 2);

                % phi와 theta에 해당하는 점을 검은색 점으로 표시
                plot(app.UIAxes, x_phi, y_phi, 'ko', 'MarkerSize', 10, 'MarkerFaceColor', 'k');  % 검은색 점 (x_phi, y_phi)
                plot(app.UIAxes, x_theta, y_theta, 'ko', 'MarkerSize', 10, 'MarkerFaceColor', 'k');  % 검은색 점 (x_theta, y_theta)

                % x축과 y축 그리기
                xline(app.UIAxes, 0, 'k--', 'LineWidth', 1);  % x축 추가 (검은색 점선)
                yline(app.UIAxes, 0, 'k--', 'LineWidth', 1);  % y축 추가 (검은색 점선)

                % 점선으로 된 원 그리기
                plot(app.UIAxes, x_dashed_circle_theta, y_dashed_circle_theta, 'k--', 'LineWidth', 1);  % 점선 원 (x_theta, y_theta)
                plot(app.UIAxes, x_dashed_circle_phi, y_dashed_circle_phi, 'k--', 'LineWidth', 1);     % 점선 원 (x_phi, y_phi)

                % 두 원의 교점을 검은색 점으로 표시
                plot(app.UIAxes, x_intersect, y_intersect, 'ko', 'MarkerSize', 10, 'MarkerFaceColor', 'k');

                % 교점에서 x축과 y축까지 수직 및 수평선 그리기
                for i = 1:length(x_intersect)
                    % 교점에서 x축까지 수직선 그리기
                    line(app.UIAxes, [x_intersect(i), x_intersect(i)], [y_intersect(i), 0], 'Color', 'k', 'LineStyle', '--', 'LineWidth', 1);

                    % 교점에서 y축까지 수평선 그리기
                    line(app.UIAxes, [x_intersect(i), 0], [y_intersect(i), y_intersect(i)], 'Color', 'k', 'LineStyle', '--', 'LineWidth', 1);

                    % x축과 y축에 값 표시 (텍스트로)
                    text(app.UIAxes, x_intersect(i), 0, ['  ', num2str(x_intersect(i))], 'VerticalAlignment', 'bottom', 'HorizontalAlignment', 'right');
                    text(app.UIAxes, 0, y_intersect(i), ['  ', num2str(y_intersect(i))], 'VerticalAlignment', 'top', 'HorizontalAlignment', 'left');
                end

                % 축 설정
                xlabel(app.UIAxes, '\sigma');
                ylabel(app.UIAxes, 'Values');
                title(app.UIAxes, '3개의 원 및 각도에 따른 점과 점선 원 및 교점 표시');
                legend(app.UIAxes, '\sigma_x와 \sigma_y', '\sigma_y와 \sigma_z', '\sigma_x와 \sigma_z', 'phi 위치', 'theta 위치', '점선 원 (x_phi)', '교점');

                hold(app.UIAxes, 'off');
                
                %}
            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Value changed function: thetaSlider
        function thetaSliderValueChanged(app, event)
            
            theta = app.thetaSlider.Value;
            app.EditField.Value = theta;
        end

        % Value changing function: thetaSlider
        function thetaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value=changingValue;
   
        end

        % Value changing function: phiSlider
        function phiSliderValueChanging(app, event)
            newphi = event.Value;
            app.EditField_2.Value=newphi;
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            
            
            if app.EditField_2.Value >= app.phiSlider.Limits(1) && app.EditField_2.Value <= app.phiSlider.Limits(2)
                app.phiSlider.Value = app.EditField_2.Value;
            else
                % 범위를 벗어난 경우 경고 메시지 출력
                uialert(app.UIFigure, 'Value is out of slider range', 'Invalid Input');
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            if app.EditField.Value >= app.thetaSlider.Limits(1) && app.EditField_2.Value <= app.thetaSlider.Limits(2)
                app.thetaSlider.Value = app.EditField.Value;
            else
                % 범위를 벗어난 경우 경고 메시지 출력
                uialert(app.UIFigure, 'Value is out of slider range', 'Invalid Input');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '3D Mohr''s Circle')
            xlabel(app.UIAxes, 'normal stress')
            ylabel(app.UIAxes, 'shear stress')
            zlabel(app.UIAxes, 'Z')
            app.AddplotButton = uibutton(app.UIFigure, 'push');
            app.AddplotButton.ButtonPushedFcn = createCallbackFcn(app, @AddplotButtonPushed, true);
            app.AddplotButton.Text = 'Add plot';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.DMohrsCircleLabel = uilabel(app.UIFigure);
            app.DMohrsCircleLabel.Text = '3D Mohr''s Circle';
            app.PrincipalStressesPanel = uipanel(app.UIFigure);
            app.PrincipalStressesPanel.Title = 'Principal Stresses';
            app.textittheta_3Label = uilabel(app.PrincipalStressesPanel);
            app.textittheta_3Label.Interpreter = 'latex';
            app.textittheta_3Label.Text = '$\sigma_{x}$ (MPa)';
            app.sigma_xMPaEditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.sigma_yMPaEditFieldLabel = uilabel(app.PrincipalStressesPanel);
            app.sigma_yMPaEditFieldLabel.Interpreter = 'latex';
            app.sigma_yMPaEditFieldLabel.Text = '$\sigma_{y}$ (MPa)';
            app.sigma_yMPaEditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.sigma_zMPaEditFieldLabel = uilabel(app.PrincipalStressesPanel);
            app.sigma_zMPaEditFieldLabel.Interpreter = 'latex';
            app.sigma_zMPaEditFieldLabel.Text = '$\sigma_{z}$ (MPa)';
            app.sigma_zMPaEditField = uieditfield(app.PrincipalStressesPanel, 'numeric');
            app.thetaSliderLabel = uilabel(app.UIFigure);
            app.thetaSliderLabel.Text = 'theta';
            app.thetaSlider = uislider(app.UIFigure);
            app.thetaSlider.Limits = [0 90];
            app.thetaSlider.ValueChangedFcn = createCallbackFcn(app, @thetaSliderValueChanged, true);
            app.thetaSlider.ValueChangingFcn = createCallbackFcn(app, @thetaSliderValueChanging, true);
            app.phiSliderLabel = uilabel(app.UIFigure);
            app.phiSliderLabel.Text = 'phi';
            app.phiSlider = uislider(app.UIFigure);
            app.phiSlider.Limits = [0 90];
            app.phiSlider.ValueChangingFcn = createCallbackFcn(app, @phiSliderValueChanging, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%3.1f';
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2.ValueDisplayFormat = '%3.1f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.range090Label = uilabel(app.UIFigure);
            app.range090Label.Text = '(range : 0~90)';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'matlab files&example data_1.2', '스크린샷 2024-08-26 164945.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mohr_Circle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end