classdef SIngleOri2nd_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FIleMenu                       matlab.ui.container.Menu
        SaveRawDataMenu                matlab.ui.container.Menu
        LocalFailureAnalysisButton     matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        PlottingOptionPanel            matlab.ui.container.Panel
        degreeLabel                    matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        LineGraphButton                matlab.ui.control.RadioButton
        ContourGraphButton             matlab.ui.control.RadioButton
        DropDown                       matlab.ui.control.DropDown
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        EditField_4                    matlab.ui.control.NumericEditField
        EditField_3                    matlab.ui.control.NumericEditField
        ByButton                       matlab.ui.control.RadioButton
        ByRButton                      matlab.ui.control.RadioButton
        FailureCriteriaPanel           matlab.ui.container.Panel
        UCSEditField                   matlab.ui.control.NumericEditField
        DegreeLabel                    matlab.ui.control.Label
        MPaLabel_2                     matlab.ui.control.Label
        MPaLabel                       matlab.ui.control.Label
        TensileStrengthEditField       matlab.ui.control.NumericEditField
        TensileStrengthEditFieldLabel  matlab.ui.control.Label
        FrictionAngleEditField         matlab.ui.control.NumericEditField
        FrictionAngleEditFieldLabel    matlab.ui.control.Label
        UCSEditFieldLabel              matlab.ui.control.Label
        ApplyfailurecriteriaSwitch     matlab.ui.control.Switch
        ApplyfailurecriteriaSwitchLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CrossLineX                matlab.graphics.shape.Line
        CrossLineY                matlab.graphics.shape.Line
    end
    
    methods (Access = public)
        
        % Mouse move function
        function mouseMove(app,src, event)
            % Get current mouse position in the figure
            currentPoint = app.UIFigure.CurrentPoint;
            figPos = app.UIFigure.Position;
            
            % Normalize mouse position
            x = currentPoint(1) / figPos(3);
            y = currentPoint(2) / figPos(4); 
            
            % Update cross lines
            app.CrossLineX.Position = [x 0 0 1];
            app.CrossLineY.Position = [0 y 1 0];
        end
            
    end
   
    
    methods (Access = private)
        
        function mouseButtonDown(app, src, event)
            % Check if the right mouse button is clicked
            if strcmp(app.UIFigure.SelectionType, 'alt')
                % Disable mouse motion callback
                app.UIFigure.WindowButtonMotionFcn = [];
                % Disable mouse button down callback
                app.UIFigure.WindowButtonDownFcn = [];
                % Delete cross lines
                if isvalid(app.CrossLineX)
                    delete(app.CrossLineX);
                end
                if isvalid(app.CrossLineY)
                    delete(app.CrossLineY);
                end
                app.UIFigure.Pointer='arrow';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ApplyfailurecriteriaSwitch
        function ApplyfailurecriteriaSwitchValueChanged(app, event)
             try
                onoff = app.ApplyfailurecriteriaSwitch.Value; 
        
                if strcmp(onoff, 'Off')                
                    app.UCSEditField.Editable = 'off';
                    app.FrictionAngleEditField.Editable = 'off'; 
                    app.TensileStrengthEditField.Editable='off';
                    app.UCSEditField.Value=[];
                    app.FrictionAngleEditField.Value=[];
                    app.TensileStrengthEditField.Value=[];
                elseif strcmp(onoff, 'On')
                    app.UCSEditField.Editable = 'on';
                    app.FrictionAngleEditField.Editable = 'on'; 
                    app.TensileStrengthEditField.Editable='on';
                end
            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            if selectedButton == app.ContourGraphButton
                
                app.EditField_3.Value=[];
                app.EditField_4.Value=[];
                app.EditField_4.Editable='off';
                app.EditField_3.Editable="off";
                app.ByButton.Enable="off";
                app.ByRButton.Enable="off";
                
            elseif selectedButton == app.LineGraphButton
                
                app.EditField_3.Editable='on';
                app.EditField_4.Editable="on";
                app.ByButton.Enable="on";
                app.ByRButton.Enable="on";
              
            end
           
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            
            load('vars.mat','Gr','Sr','St','gridr','gridtheta','Sz','Trt','Trz','Ttz','Sxx','Syy',...
                    'Szz','Txy','Tyz','Txz','noder','nodetheta','dispxx','dispyy',...
                    'Sp1','Sp2','Sp3')

            reset(app.UIAxes)
            try
                % Get selected value from ctype dropdown
                switch app.DropDown.Value
                    case 'Tangential Stress'
                        [contx, conty] = pol2cart(gridtheta, gridr);
                        plotvalue = St * 10^-6;
                        plottitle = 'Tangential Stress (MPa)';
                    case 'Radial Stress'
                        [contx, conty] = pol2cart(gridtheta, gridr);
                        plotvalue = Sr * 10^-6;
                        plottitle = 'Radial Stress (MPa)';
                    case 'Axial Stress'
                        [contx, conty] = pol2cart(gridtheta, gridr);
                        plotvalue = Sz * 10^-6;
                        plottitle = 'Axial Stress (MPa)';
                    case 'Maximum Principal Stress'
                        [contx, conty] = pol2cart(gridtheta, gridr);
                        plotvalue = Sp1 * 10^-6;
                        plottitle = 'Maximum Principal Stress (MPa)';
                    case 'Minimum Principal Stress'
                        [contx, conty] = pol2cart(gridtheta, gridr);
                        plotvalue = Sp3 * 10^-6;
                        plottitle = 'Minimum Principal Stress (MPa)';
                    case 'Tresca Stress'
                        [contx, conty] = pol2cart(gridtheta, gridr);
                        plotvalue = (Sp1 - Sp3) * 10^-6;
                        plottitle = 'Tresca Stress (MPa)';
                    case 'Von-mises Stress'
                        [contx, conty] = pol2cart(gridtheta, gridr);
                        plotvalue = ((1/sqrt(2)) * ((Sp1 - Sp2).^2 + (Sp2 - Sp3).^2 + (Sp3 - Sp1).^2).^(1/2)) * 10^-6;
                        plottitle = 'Von-mises Stress';
                end

                if app.ContourGraphButton.Value == 1

                    pcolor(app.UIAxes, contx, conty, plotvalue);
                    shading(app.UIAxes, 'interp');
                    colormap(app.UIAxes, 'jet');
                    daspect(app.UIAxes, [1 1 1]);
                    limdata = Gr * 3;
                    xlim(app.UIAxes, [-limdata limdata]);
                    ylim(app.UIAxes, [-limdata limdata]);
                    title(app.UIAxes, plottitle);
                    colorbar(app.UIAxes);
                    caxis(app.UIAxes, [min(min(plotvalue)) max(max(plotvalue))]);

                   Gucs = 0;
                   Gfri = 0;
                   Gten = 0;
                   Whradsta = 0;

                     if strcmp(app.ApplyfailurecriteriaSwitch.Value, 'On') 
                        Whradsta = 1;
                        Gucs = app.UCSEditField.Value * 10^6;
                        Gfri = app.FrictionAngleEditField.Value * 2 * pi / 360;
                        Gten = app.TensileStrengthEditField.Value * 10^6;
                        sign = -(Gucs + Sp3 .* (1 + sin(Gfri)) ./ (1 - sin(Gfri)) - Sp1);
                        sign2 = -(Sp3 + Gten);
                        hold(app.UIAxes, 'on');
                        contour(app.UIAxes, contx, conty, sign, [0 0], 'k', 'LineWidth', 1.5);
                        contour(app.UIAxes, contx, conty, sign2, [0 0], 'r', 'LineWidth', 1.5);
                        hold(app.UIAxes, 'off');
                    end
                end

                if app.LineGraphButton.Value == 1
                    if app.ByRButton.Value == 1
                        plotr = app.EditField_3.Value;
                        [~, minr2] = min((gridr(:, 1) - plotr).^2);
                        plot(app.UIAxes, gridtheta(minr2, :) * 360 / (2 * pi), plotvalue(minr2, :));
                        title(app.UIAxes, plottitle);
                        xlabel(app.UIAxes, 'Angle(degree)');
                        ylabel(app.UIAxes, 'Stress(MPa)');
                        xlim(app.UIAxes, [0 360]);
                    end
                    if app.ByButton.Value == 1
                        plottheta = (app.EditField_4.Value) / 360 * 2 * pi;
                        [~, minth2] = min((gridtheta(1, :) - plottheta).^2);
                        plot(app.UIAxes, gridr(:, minth2), plotvalue(:, minth2));
                        title(app.UIAxes, plottitle);
                        xlabel(app.UIAxes, 'Radius(m)');
                        ylabel(app.UIAxes, 'Stress(MPa)');
                    end
                end

            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end
       
        end

        % Button pushed function: LocalFailureAnalysisButton
        function LocalFailureAnalysisButtonPushed(app, event)
            currentPoint = app.UIFigure.CurrentPoint;
            figPos = app.UIFigure.Position;
            
            app.UIFigure.Pointer="cross";
            % Normalize mouse position
            x = currentPoint(1) / figPos(3);
            y = currentPoint(2) / figPos(4);

                   
            % Create cross lines as annotations
            app.CrossLineX = annotation(app.UIFigure, 'line', [x x], [0 1], 'Color', 'r');
            app.CrossLineY = annotation(app.UIFigure, 'line', [0 1], [y y], 'Color', 'r');

            % Enable mouse motion callback
            app.UIFigure.WindowButtonMotionFcn = @(src, event) app.mouseMove(src, event);

            % Enable mouse button down callback
            app.UIFigure.WindowButtonDownFcn = @(src, event) app.mouseButtonDown(src, event);
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FIleMenu = uimenu(app.UIFigure);
            app.FIleMenu.Text = 'FIle';
            app.SaveRawDataMenu = uimenu(app.FIleMenu);
            app.SaveRawDataMenu.Text = 'Save Raw Data';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.FailureCriteriaPanel = uipanel(app.UIFigure);
            app.FailureCriteriaPanel.Title = 'Failure Criteria';
            app.ApplyfailurecriteriaSwitchLabel = uilabel(app.FailureCriteriaPanel);
            app.ApplyfailurecriteriaSwitchLabel.Text = 'Apply failure criteria';
            app.ApplyfailurecriteriaSwitch = uiswitch(app.FailureCriteriaPanel, 'slider');
            app.ApplyfailurecriteriaSwitch.ValueChangedFcn = createCallbackFcn(app, @ApplyfailurecriteriaSwitchValueChanged, true);
            app.UCSEditFieldLabel = uilabel(app.FailureCriteriaPanel);
            app.UCSEditFieldLabel.Text = 'UCS';
            app.FrictionAngleEditFieldLabel = uilabel(app.FailureCriteriaPanel);
            app.FrictionAngleEditFieldLabel.Text = {'Friction'; 'Angle'};
            app.FrictionAngleEditField = uieditfield(app.FailureCriteriaPanel, 'numeric');
            app.FrictionAngleEditField.AllowEmpty = 'on';
            app.FrictionAngleEditField.Editable = 'off';
            app.TensileStrengthEditFieldLabel = uilabel(app.FailureCriteriaPanel);
            app.TensileStrengthEditFieldLabel.Text = {'Tensile'; 'Strength'};
            app.TensileStrengthEditField = uieditfield(app.FailureCriteriaPanel, 'numeric');
            app.TensileStrengthEditField.AllowEmpty = 'on';
            app.TensileStrengthEditField.Editable = 'off';
            app.MPaLabel = uilabel(app.FailureCriteriaPanel);
            app.MPaLabel.Text = 'MPa';
            app.MPaLabel_2 = uilabel(app.FailureCriteriaPanel);
            app.MPaLabel_2.Text = 'MPa';
            app.DegreeLabel = uilabel(app.FailureCriteriaPanel);
            app.DegreeLabel.Text = 'Degree';
            app.UCSEditField = uieditfield(app.FailureCriteriaPanel, 'numeric');
            app.UCSEditField.AllowEmpty = 'on';
            app.UCSEditField.Editable = 'off';
            app.PlottingOptionPanel = uipanel(app.UIFigure);
            app.PlottingOptionPanel.Title = 'Plotting Option';
            app.ButtonGroup_2 = uibuttongroup(app.PlottingOptionPanel);
            app.ByRButton = uiradiobutton(app.ButtonGroup_2);
            app.ByRButton.Enable = 'off';
            app.ByRButton.Text = 'By R = ';
            app.ByRButton.Value = true;
            app.ByButton = uiradiobutton(app.ButtonGroup_2);
            app.ByButton.Enable = 'off';
            app.ByButton.Text = 'By θ = ';
            app.EditField_3 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.EditField_3.AllowEmpty = 'on';
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = [];
            app.EditField_4 = uieditfield(app.ButtonGroup_2, 'numeric');
            app.EditField_4.AllowEmpty = 'on';
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = [];
            app.DropDown = uidropdown(app.PlottingOptionPanel);
            app.DropDown.Items = {'Tangential Stress', 'Radial Stress', 'Z-axis Stress', 'Maximum Prinicipal Stress', 'Minimum Principal Stress', 'Tresca Stress', 'Von-Mises Stress'};
            app.DropDown.Value = 'Tangential Stress';
            app.ButtonGroup = uibuttongroup(app.PlottingOptionPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ContourGraphButton = uiradiobutton(app.ButtonGroup);
            app.ContourGraphButton.Text = 'Contour Graph';
            app.ContourGraphButton.Value = true;
            app.LineGraphButton = uiradiobutton(app.ButtonGroup);
            app.LineGraphButton.Text = 'Line Graph';
            app.mLabel = uilabel(app.PlottingOptionPanel);
            app.mLabel.Text = 'm';
            app.degreeLabel = uilabel(app.PlottingOptionPanel);
            app.degreeLabel.Text = 'degree';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LocalFailureAnalysisButton = uibutton(app.UIFigure, 'push');
            app.LocalFailureAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LocalFailureAnalysisButtonPushed, true);
            app.LocalFailureAnalysisButton.Text = 'Local Failure Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIngleOri2nd_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end