classdef anisotropy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StrengthAnisotropyAnalysisLabel  matlab.ui.control.Label
        ClearButton                    matlab.ui.control.Button
        AddplotButton                  matlab.ui.control.Button
        sigma_3MPaEditField            matlab.ui.control.NumericEditField
        textittheta_3Label             matlab.ui.control.Label
        rockfrictionangleDegreeEditField  matlab.ui.control.NumericEditField
        rockfrictionangleDegreeLabel   matlab.ui.control.Label
        rockcohesionMPaEditField       matlab.ui.control.NumericEditField
        rockcohesionMPaEditFieldLabel  matlab.ui.control.Label
        FracturefrictionangleDegreeEditField  matlab.ui.control.NumericEditField
        FracturefrictionangleDegreeEditFieldLabel  matlab.ui.control.Label
        FracturecohesionMPaEditField   matlab.ui.control.NumericEditField
        FracturecohesionMPaEditFieldLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function y=aniso(~,frac_cohesion,frac_fric_ang,rock_cohesion,rock_fric_ang,sigma_3)
            UCS=2*rock_cohesion*cos(deg2rad(rock_fric_ang))/(1-sin(deg2rad(rock_fric_ang)))+sigma_3*(tand(45+rock_fric_ang/2))^2;
            
            y=zeros(1,length(1:1:89));
            for i = 1:899
                arad=i/10;
                y(i)=(frac_cohesion*cosd(frac_fric_ang)+sigma_3*sind(arad+frac_fric_ang)*cosd(arad))/(cosd(arad+frac_fric_ang)*sind(arad));

                if y(i)>UCS
                    y(i)=UCS;
                elseif arad> 90-frac_fric_ang
                    y(i)=UCS;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddplotButton
        function AddplotButtonPushed(app, event)
            try
                xaxis=0.1:0.1:89.9;
                value=app.aniso(app.FracturecohesionMPaEditField.Value,...
                    app.FracturefrictionangleDegreeEditField.Value,...
                    app.rockcohesionMPaEditField.Value, ...
                    app.rockfrictionangleDegreeEditField.Value,...
                    app.sigma_3MPaEditField.Value);
                hold(app.UIAxes, 'on');
                plot(app.UIAxes, xaxis, value,LineWidth=2);
                grid(app.UIAxes,"on");
                xlabel(app.UIAxes, 'Deg');
                ylabel(app.UIAxes, 'MPa');
            catch ex
                errmsg = ex.stack.line;
                uialert(app.UIFigure, sprintf('Error found in line %d\n%s', errmsg, ex.message), 'Error');
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            cla(app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Strength Anisotropy')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FracturecohesionMPaEditFieldLabel = uilabel(app.UIFigure);
            app.FracturecohesionMPaEditFieldLabel.Interpreter = 'latex';
            app.FracturecohesionMPaEditFieldLabel.Text = 'Fracture cohesion (MPa)';
            app.FracturecohesionMPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FracturefrictionangleDegreeEditFieldLabel = uilabel(app.UIFigure);
            app.FracturefrictionangleDegreeEditFieldLabel.Interpreter = 'latex';
            app.FracturefrictionangleDegreeEditFieldLabel.Text = {'Fracture friction angle'; '(Degree)'};
            app.FracturefrictionangleDegreeEditField = uieditfield(app.UIFigure, 'numeric');
            app.rockcohesionMPaEditFieldLabel = uilabel(app.UIFigure);
            app.rockcohesionMPaEditFieldLabel.Interpreter = 'latex';
            app.rockcohesionMPaEditFieldLabel.Text = 'rock cohesion (MPa)';
            app.rockcohesionMPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.rockfrictionangleDegreeLabel = uilabel(app.UIFigure);
            app.rockfrictionangleDegreeLabel.Interpreter = 'latex';
            app.rockfrictionangleDegreeLabel.Text = {'rock friction angle'; '(Degree)'};
            app.rockfrictionangleDegreeEditField = uieditfield(app.UIFigure, 'numeric');
            app.textittheta_3Label = uilabel(app.UIFigure);
            app.textittheta_3Label.Interpreter = 'latex';
            app.textittheta_3Label.Text = '$\sigma_{3}$ (MPa)';
            app.sigma_3MPaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddplotButton = uibutton(app.UIFigure, 'push');
            app.AddplotButton.ButtonPushedFcn = createCallbackFcn(app, @AddplotButtonPushed, true);
            app.AddplotButton.Text = 'Add plot';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.StrengthAnisotropyAnalysisLabel = uilabel(app.UIFigure);
            app.StrengthAnisotropyAnalysisLabel.Text = 'Strength Anisotropy Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = anisotropy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end